classdef adjust_multicolor_image < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        UIAxes                    matlab.ui.control.UIAxes
        RedSliderLabel            matlab.ui.control.Label
        RedSlider                 matlab.ui.control.Slider
        GreenSliderLabel          matlab.ui.control.Label
        GreenSlider               matlab.ui.control.Slider
        BlueSliderLabel           matlab.ui.control.Label
        BlueSlider                matlab.ui.control.Slider
        LoadButton                matlab.ui.control.Button
        RemoveOutliersButton      matlab.ui.control.Button
        RotateLabel               matlab.ui.control.Label
        ResetSlidersButton        matlab.ui.control.Button
        SaveButton                matlab.ui.control.Button
        GammaGreenSlider          matlab.ui.control.Slider
        GammaBlueSlider           matlab.ui.control.Slider
        GammaRedSlider            matlab.ui.control.Slider
        Knob                      matlab.ui.control.Knob
        XButton                   matlab.ui.control.StateButton
        YButton                   matlab.ui.control.StateButton
        WeightLabel               matlab.ui.control.Label
        GammaLabel                matlab.ui.control.Label
        RollKnob                  matlab.ui.control.Knob
        CropButton                matlab.ui.control.Button
        Note_2                    matlab.ui.control.Label
        FlipLabel                 matlab.ui.control.Label
        TitleLabel                matlab.ui.control.Label
        SubtractBackgroundButton  matlab.ui.control.Button
        SubBeforeButton           matlab.ui.control.StateButton
        SubAfterButton            matlab.ui.control.StateButton
    end

    
    properties (Access = private)
        path;
        full_file;
        image; % the image we are currently working with
        num_channels;
        zero_mask;
        crop_mask;
        original_volume;
        adjusted_volume;
        display_image;
        base_image;
        gamma_val;
        background_mask;
        
        roll_axis = [1, 0, 0];
        rgb_indicies = [5, 3, 1];
    end
    
    methods (Access = private)
        function max_val = max_of_channel(~, image_in, num_from_max)
            % max_of_channel  returns the max value in each channel of an
            % image
            if nargin < 3
                num_from_max = 100;
            end
            
            image_size = size(image_in);
            
            switch length(size(image_in))
                case 3
                    columnized_image = reshape(image_in, [image_size(1)*image_size(2), image_size(3)]);
                    sorted_image = sort(columnized_image, 1, 'descend');
                    max_val = permute(mean(sorted_image(1:num_from_max, :)), [1, 3, 2]);
                case 4
                    columnized_image = reshape(image_in, [image_size(1)*image_size(2)*image_size(3), image_size(4)]);
                    sorted_image = sort(columnized_image, 1, 'descend');
                    max_val = permute(mean(sorted_image(1:num_from_max, :)), [1, 3, 4, 2]);
            end
        end
        
        function construct_image(app, apply_gamma)
            % construct_image  applies each adjustment to a volume in order
            % then generates a 2d image to display
            % adjustments are applied in order:
            % Zero masking
            % Normalizing
            % Weighting each color channel
            % Flipping image in xyz
            % Rotate image around z axis
            % Roll image around x axis
            
            if nargin < 2
                apply_gamma = true;
            end
            
            app.gamma_val = ones(app.num_channels, 1) / 2;
            app.gamma_val(app.rgb_indicies) = [app.GammaRedSlider.Value, app.GammaGreenSlider.Value, app.GammaBlueSlider.Value];
                
                
            if app.SubBeforeButton.Value
                background_value = zeros(1, 1, 1, app.num_channels);
                
                %% background subtract
                if any(app.background_mask(:))
                    for cc = 1:app.num_channels
                        for ss = 1:size(app.original_volume, 3)
                            this_frame = app.original_volume(:, :, ss, cc);
                            background_value(cc) = background_value(cc) + sum(this_frame(app.background_mask), 'all');
                        end
                    end
                    
                    background_value = background_value / sum(app.background_mask, 'all') / size(app.original_volume, 3);
                end
                
                %% zero_mask image
                zero_masked_volume = app.original_volume .* app.zero_mask - background_value;
            else
                zero_masked_volume = app.original_volume .* app.zero_mask;
            end
            
            cropped_volume = zero_masked_volume(app.crop_mask(1, 1):app.crop_mask(1,2), app.crop_mask(2,1):app.crop_mask(2,2), :, :);
            
            %% normalize image
            max_val = app.max_of_channel(cropped_volume);
            normalized_zero_masked_volume = cropped_volume ./ max_val;
            normalized_zero_masked_volume(normalized_zero_masked_volume > 1) = 1;
            
            if apply_gamma
                %% gamma image
                app.gamma_val = ones(app.num_channels, 1) / 2;
                app.gamma_val(app.rgb_indicies) = [app.GammaRedSlider.Value, app.GammaGreenSlider.Value, app.GammaBlueSlider.Value];
                
                gamma_image = zeros(size(normalized_zero_masked_volume));
                for ss = 1:size(normalized_zero_masked_volume, 3)
                    for ff = 1:app.num_channels
                        gamma_image(:, :, ss, ff) = imadjust(normalized_zero_masked_volume(:, :, ss, ff), [], [], app.gamma_val(ff));
                    end
                end
            else
                gamma_image = normalized_zero_masked_volume;
            end
            
            if app.SubAfterButton.Value
                %% emperically, background subtract after gamma seems to work better
                background_value = zeros(1, 1, 1, app.num_channels);
                orig_color_max = app.max_of_channel(app.original_volume);
                
                %% background subtract
                if any(app.background_mask(:))
                    for ss = 1:size(app.original_volume, 3)
                        for cc = 1:app.num_channels
                            this_frame = app.original_volume(:, :, ss, cc) / orig_color_max(cc);
                            this_frame(this_frame > 1) = 1;
                            this_frame = imadjust(this_frame, [], [], app.gamma_val(cc));
                            background_value(cc) = background_value(cc) + sum(this_frame(app.background_mask), 'all');
                        end
                    end
                    
                    background_value = background_value / sum(app.background_mask, 'all') / size(app.original_volume, 3);
                end
                
                gamma_image = gamma_image - background_value;
            end
            
            %% normalize image
            max_val = app.max_of_channel(gamma_image);
            gamma_image = gamma_image ./ max_val;
            gamma_image(gamma_image > 1) = 1;
            
            %% weight image
            slider_weights = app.get_weights();
            
            max_weights = max([max(slider_weights) 0.05]);
            
            weights = ones(1, 1, 1, app.num_channels) * max(slider_weights);
            weights(app.rgb_indicies) = slider_weights;
            
            weighted_image = gamma_image .* weights ./ max_weights;
            
            %% flip_image
            if app.XButton.Value
                weighted_image = flip(weighted_image, 2);
            end
            
            if app.YButton.Value
                weighted_image = flip(weighted_image, 1);
            end
            
            % flip z axis if either x or y has been flipped (but not both)
            if xor(app.XButton.Value, app.YButton.Value)
                weighted_image = flip(weighted_image, 3);
            end
            
            %% rotate image
            rotated_volume = imrotate(weighted_image, -app.Knob.Value, 'bilinear');
            
            %% roll image
            % requires upsampling the image in z to match sample rate in xy
            if app.RollKnob.Value ~= 0
                scale = app.full_file.original_scale;
                
                % check if all scales are the same. if not interpolate
                im_size = size(rotated_volume);
                x = (0:im_size(2)-1)'*scale(2);
                y = (0:im_size(1)-1)'*scale(2);
                z = (0:im_size(3)-1)'*scale(3);
                
                [mesh_x, mesh_y, mesh_z] = meshgrid(x, y, z);
                
                new_scale = min(scale);
                
                xq = 0:new_scale:x(end);
                yq = 0:new_scale:y(end);
                zq = 0:new_scale:z(end);
                
                [mesh_xq, mesh_yq, mesh_zq] = meshgrid(xq, yq, zq);
                
                resampled_volume = zeros(size(mesh_xq));
                for cc = 1:app.num_channels
                    resampled_volume(:, :, :, cc) = interp3(mesh_x, mesh_y, mesh_z, rotated_volume(:, :, :, cc), mesh_xq, mesh_yq, mesh_zq);
                end
                
                app.full_file.original_volume = resampled_volume;
                
                app.full_file.scale = [new_scale, new_scale, new_scale];
                
                
                rolled_volume = imrotate3(resampled_volume(:, :, :, 1), -app.RollKnob.Value, app.roll_axis, 'linear');
                app.adjusted_volume = zeros([size(rolled_volume) app.num_channels]);
                app.adjusted_volume(:, :, :, 1) = rolled_volume;
                
                for rr = 2:app.num_channels
                    app.adjusted_volume(:, :, :, rr) = imrotate3(resampled_volume(:, :, :, rr), -app.RollKnob.Value, app.roll_axis, 'linear');
                end
            else
                app.adjusted_volume = rotated_volume;
            end
            
            % compress to 2d image
            app.display_image = app.get_max_proj(app.adjusted_volume);
        end
        
        function plot_zstack(app)
            app.construct_image();
            
            imagesc(app.UIAxes, app.display_image);
        end
        
        function max_proj = get_max_proj(app, volume)
            max_proj = squeeze(max(volume(:, :, :, app.rgb_indicies), [], 3));
        end
        
        function weights = get_weights(app)
            % read weights from the sliders
            weights = zeros(3, 1);
            weights(1) = app.RedSlider.Value;
            weights(2) = app.GreenSlider.Value;
            weights(3) = app.BlueSlider.Value;
        end
        
        function reset_sliders(app)
            % reset weights
            app.RedSlider.Value = app.full_file.weights(5);
            app.GreenSlider.Value = app.full_file.weights(3);
            app.BlueSlider.Value = app.full_file.weights(1);
            
            % reset gamma
            app.GammaRedSlider.Value = app.full_file.gamma_val(5);
            app.GammaGreenSlider.Value = app.full_file.gamma_val(3);
            app.GammaBlueSlider.Value = app.full_file.gamma_val(1);
            
            % reset flip
            app.XButton.Value = app.full_file.flipX;
            app.YButton.Value = app.full_file.flipY;
            
            % reset rotation
            app.Knob.Value = app.full_file.rotation;
            app.RollKnob.Value = app.full_file.roll_val;
            
            app.plot_zstack();
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            axis(app.UIAxes, 'equal');
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            config = get_config();
            
            [file, path_in] = uigetfile([config.multicolor_path '/*multicolor_adjustment.mat'], 'Select multicolor_adjustment file in multicolor folder');
            
            if all(file == 0)
                return;
            end
            
            app.path = fullfile(path_in, file);
            app.full_file = load(app.path, '-mat');
            
            [date_folder_path, enclosing_folder] = fileparts(path_in(1:end-1));
            [~, date_folder] = fileparts(date_folder_path);
            file_name = fullfile(date_folder, enclosing_folder);
            
            % set the title to reflect the file you loaded
            app.TitleLabel.Text = file_name;
            
            % grab multicolor data
            app.original_volume = app.full_file.original_data;
            app.zero_mask = app.full_file.zero_mask;
            app.crop_mask = app.full_file.crop_mask;
            
            app.num_channels = size(app.original_volume, 4);
            
            app.base_image = app.get_max_proj(app.original_volume);
            app.base_image = app.base_image ./ max(max(app.base_image));
            
            % set the background region
            if isfield(app.full_file, 'background_mask')
                app.background_mask = app.full_file.background_mask;
            else
                app.background_mask = false;
            end
            
            app.reset_sliders();
        end

        % Button pushed function: RemoveOutliersButton
        function RemoveOutliersButtonPushed(app, event)
            % make zero_mask. This mask will be used to set all values
            % in mask to 0
            
            h = figure;
            imagesc(app.base_image);
            axis('equal');
            roi = drawpolygon;
            app.zero_mask = ~roi.createMask;
            close(h);
            
            app.plot_zstack();
        end

        % Value changed function: RedSlider
        function RedSliderValueChanged(app, event)
            app.plot_zstack();
        end

        % Value changed function: GreenSlider
        function GreenSliderValueChanged(app, event)
            app.plot_zstack();
        end

        % Value changed function: BlueSlider
        function BlueSliderValueChanged(app, event)
            app.plot_zstack();
        end

        % Button pushed function: ResetSlidersButton
        function ResetSlidersButtonPushed(app, event)
            app.reset_sliders();
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            app.SaveButton.Text = 'PLEASE WAIT';
            pause(0.1);
            
            % save all the modification values so that if the file is
            % loaded again the user starts where they left off.
            weights = ones(app.num_channels, 1);
            weights(app.rgb_indicies) = app.get_weights();
            
            adjustment_to_save = app.full_file;
            adjustment_to_save.gamma_val = app.gamma_val;
            adjustment_to_save.zero_mask = app.zero_mask;
            adjustment_to_save.weights = weights;
            adjustment_to_save.flipX = app.XButton.Value;
            adjustment_to_save.flipY = app.YButton.Value;
            adjustment_to_save.rotation = app.Knob.Value;
            adjustment_to_save.crop_mask = app.crop_mask;
            adjustment_to_save.roll_val = app.RollKnob.Value;
            adjustment_to_save.background_mask = app.background_mask;
            
            % we used to construct the image without gamma so that gamma
            % could be applied in visualize_light. However, since
            % background subtraction happens afterwards, it broke this path
            % until I add it into visualize light. for now do all
            % processing here and set downstream gamma to 1
%             app.construct_image(false);
            adjustment_to_save.data = app.adjusted_volume;
            save(app.path, '-struct', 'adjustment_to_save');
            
            %% save .mat file
            file_folder = fileparts(app.path);
            file_path = fullfile(file_folder, 'neuropal_data.mat');
            
            data_as_int = uint16(app.adjusted_volume*(2^16-1));
            neuropal_data = get_default_neuropal_input(data_as_int, file_path);
            neuropal_data.info.scale = app.full_file.scale;
            
            % gamma is broken because background subtraction happens after
            % gamma and isn't in visualize_light currently
%             neuropal_data.info.gamma = app.gamma_val(app.rgb_indicies);
%             neuropal_data.prefs.gamma = app.gamma_val(app.rgb_indicies);
            neuropal_data.info.gamma = ones(size(app.rgb_indicies));
            neuropal_data.prefs.gamma = ones(size(app.rgb_indicies));
            
            save(file_path, '-struct', 'neuropal_data');
            
            % used to delete old ID files, for now we will keep them. If
            % the user wants to get new cell_IDs they'll rerun it
%             old_file_path = fullfile(file_folder, 'neuropal_data_ID.mat');
%             if exist(old_file_path, 'file')
%                 delete(old_file_path);
%             end
            
            % old neuropal trimmed neuropal files
            old_file_path = fullfile(file_folder, 'neuropal_data_trimmed.mat');
            if exist(old_file_path, 'file')
                delete(old_file_path);
            end
            
            % delete old trimmed ID file
            old_file_path = fullfile(file_folder, 'neuropal_data_trimmed_ID.mat');
            if exist(old_file_path, 'file')
                delete(old_file_path);
            end
            
            % check if a calcium to multicolor alignment exists
            % if it does, create a trimmed multicolor file
            panneuronal_folder = fileparts(fileparts(app.path(1:end-1)));
            multicolor_alignment_path = fullfile(panneuronal_folder, 'calcium_to_multicolor_alignment.mat');
            
            if exist(multicolor_alignment_path, 'file')
                create_trimmed_multicolor_file(panneuronal_folder);
            end
            
            disp('Saved!');
            
            app.SaveButton.Text = 'Save';
        end

        % Value changed function: GammaRedSlider
        function GammaRedSliderValueChanged(app, event)
            app.plot_zstack();
        end

        % Value changed function: GammaGreenSlider
        function GammaGreenSliderValueChanged(app, event)
            app.plot_zstack();
        end

        % Value changed function: GammaBlueSlider
        function GammaBlueSliderValueChanged(app, event)
            app.plot_zstack();
        end

        % Value changed function: Knob
        function KnobValueChanged(app, event)
            app.plot_zstack();
        end

        % Value changed function: YButton
        function YButtonValueChanged(app, event)
            app.plot_zstack();
        end

        % Value changed function: XButton
        function XButtonValueChanged(app, event)
            app.plot_zstack();
        end

        % Value changed function: RollKnob
        function RollKnobValueChanged(app, event)
            app.plot_zstack();
        end

        % Button pushed function: CropButton
        function CropButtonPushed(app, event)
            h = figure;
            imagesc(app.base_image);
            axis('equal');
            roi = drawrectangle;
            binary_crop_mask = roi.createMask;
            close(h);
            
            first = find(binary_crop_mask, 1, 'first');
            last = find(binary_crop_mask, 1, 'last');
            
            [app.crop_mask(1, 1), app.crop_mask(2, 1)] = ind2sub(size(binary_crop_mask), first);
            [app.crop_mask(1, 2), app.crop_mask(2, 2)] = ind2sub(size(binary_crop_mask), last);
            
            app.plot_zstack();
        end

        % Button pushed function: SubtractBackgroundButton
        function SubtractBackgroundButtonPushed(app, event)
            h = figure;
            imagesc(app.base_image);
            title('select an empty region');
            axis('equal');
            roi = drawpolygon;
            app.background_mask = roi.createMask;
            close(h);
            
            app.plot_zstack();
        end

        % Value changed function: SubBeforeButton
        function SubBeforeButtonValueChanged(app, event)
            app.plot_zstack();
        end

        % Value changed function: SubAfterButton
        function SubAfterButtonValueChanged(app, event)
            value = app.SubAfterButton.Value;
            app.plot_zstack();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.RedSliderLabel = uilabel(app.UIFigure);
            app.RedSliderLabel.Text = 'Red';
            app.RedSlider = uislider(app.UIFigure);
            app.RedSlider.Limits = [0 1];
            app.RedSlider.ValueChangedFcn = createCallbackFcn(app, @RedSliderValueChanged, true);
            app.RedSlider.Value = 0.5;
            app.GreenSliderLabel = uilabel(app.UIFigure);
            app.GreenSliderLabel.Text = 'Green';
            app.GreenSlider = uislider(app.UIFigure);
            app.GreenSlider.Limits = [0 1];
            app.GreenSlider.ValueChangedFcn = createCallbackFcn(app, @GreenSliderValueChanged, true);
            app.GreenSlider.Value = 0.5;
            app.BlueSliderLabel = uilabel(app.UIFigure);
            app.BlueSliderLabel.Text = 'Blue';
            app.BlueSlider = uislider(app.UIFigure);
            app.BlueSlider.Limits = [0 1];
            app.BlueSlider.ValueChangedFcn = createCallbackFcn(app, @BlueSliderValueChanged, true);
            app.BlueSlider.Value = 0.5;
            app.LoadButton = uibutton(app.UIFigure, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.RemoveOutliersButton = uibutton(app.UIFigure, 'push');
            app.RemoveOutliersButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveOutliersButtonPushed, true);
            app.RemoveOutliersButton.Text = 'Remove Outliers';
            app.RotateLabel = uilabel(app.UIFigure);
            app.RotateLabel.Text = 'Rotate';
            app.ResetSlidersButton = uibutton(app.UIFigure, 'push');
            app.ResetSlidersButton.ButtonPushedFcn = createCallbackFcn(app, @ResetSlidersButtonPushed, true);
            app.ResetSlidersButton.Text = 'Reset Sliders';
            app.SaveButton = uibutton(app.UIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.GammaGreenSlider = uislider(app.UIFigure);
            app.GammaGreenSlider.Limits = [0 2];
            app.GammaGreenSlider.ValueChangedFcn = createCallbackFcn(app, @GammaGreenSliderValueChanged, true);
            app.GammaGreenSlider.Value = 0.8;
            app.GammaBlueSlider = uislider(app.UIFigure);
            app.GammaBlueSlider.Limits = [0 2];
            app.GammaBlueSlider.ValueChangedFcn = createCallbackFcn(app, @GammaBlueSliderValueChanged, true);
            app.GammaBlueSlider.Value = 0.4;
            app.GammaRedSlider = uislider(app.UIFigure);
            app.GammaRedSlider.Limits = [0 2];
            app.GammaRedSlider.ValueChangedFcn = createCallbackFcn(app, @GammaRedSliderValueChanged, true);
            app.GammaRedSlider.Value = 0.4;
            app.Knob = uiknob(app.UIFigure, 'continuous');
            app.Knob.Limits = [-180 180];
            app.Knob.ValueChangedFcn = createCallbackFcn(app, @KnobValueChanged, true);
            app.XButton = uibutton(app.UIFigure, 'state');
            app.XButton.ValueChangedFcn = createCallbackFcn(app, @XButtonValueChanged, true);
            app.XButton.Text = 'X';
            app.YButton = uibutton(app.UIFigure, 'state');
            app.YButton.ValueChangedFcn = createCallbackFcn(app, @YButtonValueChanged, true);
            app.YButton.Text = 'Y';
            app.WeightLabel = uilabel(app.UIFigure);
            app.WeightLabel.Text = 'Weight';
            app.GammaLabel = uilabel(app.UIFigure);
            app.GammaLabel.Text = 'Gamma';
            app.RollKnob = uiknob(app.UIFigure, 'continuous');
            app.RollKnob.Limits = [-180 180];
            app.RollKnob.ValueChangedFcn = createCallbackFcn(app, @RollKnobValueChanged, true);
            app.CropButton = uibutton(app.UIFigure, 'push');
            app.CropButton.ButtonPushedFcn = createCallbackFcn(app, @CropButtonPushed, true);
            app.CropButton.Text = 'Crop';
            app.Note_2 = uilabel(app.UIFigure);
            app.Note_2.Text = 'Roll see README';
            app.FlipLabel = uilabel(app.UIFigure);
            app.FlipLabel.Text = 'Flip';
            app.TitleLabel = uilabel(app.UIFigure);
            app.TitleLabel.Text = 'Data path';
            app.SubtractBackgroundButton = uibutton(app.UIFigure, 'push');
            app.SubtractBackgroundButton.ButtonPushedFcn = createCallbackFcn(app, @SubtractBackgroundButtonPushed, true);
            app.SubtractBackgroundButton.Text = 'Subtract Background';
            app.SubBeforeButton = uibutton(app.UIFigure, 'state');
            app.SubBeforeButton.ValueChangedFcn = createCallbackFcn(app, @SubBeforeButtonValueChanged, true);
            app.SubBeforeButton.Text = 'Sub Before';
            app.SubAfterButton = uibutton(app.UIFigure, 'state');
            app.SubAfterButton.ValueChangedFcn = createCallbackFcn(app, @SubAfterButtonValueChanged, true);
            app.SubAfterButton.Text = 'Sub After';
            app.SubAfterButton.Value = true;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = adjust_multicolor_image

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end