classdef assign_calcium_to_multicolor < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        UIAxes1                      matlab.ui.control.UIAxes
        UIAxes2                      matlab.ui.control.UIAxes
        FrameSliderLabel             matlab.ui.control.Label
        FrameSlider                  matlab.ui.control.Slider
        SaveButton                   matlab.ui.control.Button
        LoadButton                   matlab.ui.control.Button
        RotateKnobLabel              matlab.ui.control.Label
        RotateKnob                   matlab.ui.control.Knob
        XFlipButton                  matlab.ui.control.StateButton
        YFlipButton                  matlab.ui.control.StateButton
        FlipLabel                    matlab.ui.control.Label
        FrameSpinner                 matlab.ui.control.Spinner
        ImageSizeLabel               matlab.ui.control.Label
        ImageSizeOutputLabel         matlab.ui.control.Label
        NumCellsIdLabel              matlab.ui.control.Label
        NumCellsIdOutputLabel        matlab.ui.control.Label
        CalciumZDepthSliderLabel     matlab.ui.control.Label
        CalciumZDepthSlider          matlab.ui.control.Slider
        MulticolorZDepthSliderLabel  matlab.ui.control.Label
        MulticolorZDepthSlider       matlab.ui.control.Slider
        NeuronIndexLabel             matlab.ui.control.Label
        NeuronIndexSpinner           matlab.ui.control.Spinner
        SelectingneuronjumpstoDropDownLabel  matlab.ui.control.Label
        JumpList                     matlab.ui.control.DropDown
        DisplayMaxIntensityButton    matlab.ui.control.StateButton
        AssignedNeuronsTable         matlab.ui.control.Table
        CurrentKeyLabel              matlab.ui.control.Label
        CurrentKeyLabelOutput        matlab.ui.control.Label
    end

    
    properties (Access = private)
        config = get_config();
        
        % the brainscanner folder
        data_folder;
        
        % the current full volume
        calcium_stack;
        calcium_zstack; % p projection of the full stack
        multicolor_stack;
        % calcium volume after cosmetic manipulations
        rotated_calcium_stack;
        rotated_calcium_zstack;
        % the index of the currently selected calcium stack from the full
        % movie
        stack_ind;
        image_is_z_flipped; % the microscope reads top -> bottom -> top
        
        % all cell body locations across the whole movie
        calcium_cell_info; % also contains unique cell ids in trackIdx
        multicolor_cell_locations;
        % the cell body locations for the current selected timepoint
        current_calcium_locations;
        % id's for each calcium cell that are unique across all frames
        current_calcium_ids;
        % calcium cell locations after cosmetic manipulations
        rotated_calcium_cell_locations;
        % boolean of the points that are currently being displayed
        in_range_multicolor_point_inds;
        in_range_calcium_point_inds;
        
        % total number of unique cells in the calcium recording
        total_tracked_cells;
        
        % color axis for each image
        calcium_display_lim;
        multicolor_display_lim;
        
        % z location in the multicolor/calcium stack
        calcium_z;
        multicolor_z;
        
        % the data holding the timing data of the calcium movie
        hi_res_data;
        % data with the spatial alignments between red and green images
        alignments;
        
        % data from neuropal files
        neuropal_data_path;
        neuropal_data; % full neuropal file
        
        % variable storing the assignments from calcium to multicolor cell bodies
        % each index in the array corresponds to a unique calcium cell
        assignments;
        % keep track of all multicolor cells which have been assigned
        assigned_multicolor;
        
        % cosmetic manipulations to calcium image
        rotation = 0;
        flip_x = false;
        flip_y = false;
        
        % frames above and below current frame to include cell body
        % location on the display
        cell_z_range = 2;
        
        % percent of darkest pixels to use as background
        background_fraction = 0.1;
        
        % cell array of currently pressed keys
        last_key_pressed = '';
        
        % the maximum distance in pixels from a cell body to register as a click
        max_click_distance = 5;
    end
    
    methods (Access = private)
        function display_images(app)
            Assigned = sum(app.assignments ~= 0);
            Total = app.total_tracked_cells;
            assigned_table_data = table(Assigned, Total);
            app.AssignedNeuronsTable.Data = assigned_table_data;
            
            % get the image to display from the current stack
            in_range_calcium_image = app.rotated_calcium_stack(:, :, app.calcium_z);
            in_range_multicolor_image = app.multicolor_stack(:, :, app.multicolor_z);
            
            % find all cells that are assigned within this volume
            % make a boolean corresponding to each cell in the current
            % stack and whether it has been assigned
            current_assigned_calcium = app.assignments(app.current_calcium_ids) ~= 0;
            % find all multicolor cells that are assigned
            app.assigned_multicolor = false(size(app.multicolor_cell_locations, 1), 1);
            app.assigned_multicolor(app.assignments(app.assignments~=0))=true;
            
            % get the index of the cells that are currently selected
            selected_multicolor_index = app.assignments(app.NeuronIndexSpinner.Value);
            
            % find which cells are in range of the current image
            app.in_range_multicolor_point_inds = abs(app.multicolor_cell_locations(:, 3) - app.multicolor_z) <= app.cell_z_range;
            assigned_in_range_multicolor_point_inds = app.in_range_multicolor_point_inds & app.assigned_multicolor;
            in_range_multicolor_points = app.multicolor_cell_locations(app.in_range_multicolor_point_inds, :);
            assigned_in_range_multicolor_points = app.multicolor_cell_locations(assigned_in_range_multicolor_point_inds, :);
            
            app.in_range_calcium_point_inds = abs(app.rotated_calcium_cell_locations(:, 3) - app.calcium_z) < app.cell_z_range;
            assigned_in_range_calcium_point_inds = app.in_range_calcium_point_inds & current_assigned_calcium;
            in_range_calcium_points = app.rotated_calcium_cell_locations(app.in_range_calcium_point_inds, :);
            assigned_in_range_calcium_points = app.rotated_calcium_cell_locations(assigned_in_range_calcium_point_inds, :);
            selected_current_neuron_bool = app.current_calcium_ids == app.NeuronIndexSpinner.Value;
            
            % plot the calcium image with cell body locations
            if app.DisplayMaxIntensityButton.Value
                imagesc(app.UIAxes1, app.rotated_calcium_zstack);
                hold(app.UIAxes1, 'on');
                colorbar(app.UIAxes1);
                % plot currently selected cell
                scatter(app.UIAxes1, app.rotated_calcium_cell_locations(selected_current_neuron_bool, 1), app.rotated_calcium_cell_locations(selected_current_neuron_bool, 2), 'X', 'r');
                % plot all cells
                calcium_cells_h = scatter(app.UIAxes1, app.rotated_calcium_cell_locations(:, 1), app.rotated_calcium_cell_locations(:, 2), 'O', 'b');
                % plot cells
                calcium_cells_assigned_h = scatter(app.UIAxes1, app.rotated_calcium_cell_locations(current_assigned_calcium, 1), app.rotated_calcium_cell_locations(current_assigned_calcium, 2), 'O', 'g');
                hold(app.UIAxes1, 'off');
            else
                imagesc(app.UIAxes1, in_range_calcium_image);
                hold(app.UIAxes1, 'on');
                colorbar(app.UIAxes1);
                % plot currently selected cell
                scatter(app.UIAxes1, app.rotated_calcium_cell_locations(selected_current_neuron_bool, 1), app.rotated_calcium_cell_locations(selected_current_neuron_bool, 2), 'X', 'r');
                % plot all cells on this plane
                calcium_cells_h = scatter(app.UIAxes1, in_range_calcium_points(:, 1), in_range_calcium_points(:, 2), 'O', 'b');
                % plot cells that have been assigned
                calcium_cells_assigned_h = scatter(app.UIAxes1, assigned_in_range_calcium_points(:, 1), assigned_in_range_calcium_points(:, 2), 'O', 'g');
                hold(app.UIAxes1, 'off');
            end
            caxis(app.UIAxes1, app.calcium_display_lim);
            app.ImageSizeOutputLabel.Text = num2str(size(app.calcium_stack));
            colormap(app.UIAxes1, gray);
            
            % plot the multicolor image
            multicolor_image_h = imagesc(app.UIAxes2, in_range_multicolor_image);
            caxis(app.UIAxes2, app.multicolor_display_lim);
            hold(app.UIAxes2, 'on');
            % plot currently selected cell
            if app.assignments(app.NeuronIndexSpinner.Value) ~= 0
                scatter(app.UIAxes2, app.multicolor_cell_locations(selected_multicolor_index, 1), app.multicolor_cell_locations(selected_multicolor_index, 2), 'X', 'r');
            end
            % plot all cells on this plane
            multicolor_cells_h = scatter(app.UIAxes2, in_range_multicolor_points(:, 1), in_range_multicolor_points(:, 2), 'O', 'b');
            % plot all cells that have been assigned
            multicolor_cells_assigned_h = scatter(app.UIAxes2, assigned_in_range_multicolor_points(:, 1), assigned_in_range_multicolor_points(:, 2), 'O', 'g');
            hold(app.UIAxes2, 'off');
            colormap(app.UIAxes2, gray);

            num_cells = sum(~any(isnan(app.current_calcium_locations), 2));
            app.NumCellsIdOutputLabel.Text = num2str(num_cells);
            
            % set up the mouse click functions
            if ~app.DisplayMaxIntensityButton.Value
                calcium_cells_h.ButtonDownFcn = {@app.calcium_clicked};
                calcium_cells_assigned_h.ButtonDownFcn = {@app.calcium_clicked};
                multicolor_cells_h.ButtonDownFcn = {@app.multicolor_clicked};
                multicolor_cells_assigned_h.ButtonDownFcn = {@app.multicolor_clicked};
                multicolor_image_h.ButtonDownFcn = {@app.multicolor_clicked};
            end
        end
        
        function get_calcium_stack(app)
%             [initial_volumes, ~, z_loc] = app.load_calcium_from_dat(app.data_folder, app.stack_ind);
            [~, initial_volumes, z_loc] = app.load_calcium_from_dat(app.data_folder, app.stack_ind);
            app.image_is_z_flipped = z_loc(1) < z_loc(end);
            
            raw_zstack = max(initial_volumes, [], 3);
            
            % subtract the background
            % i don't have a particular reason for doing background
            % subtract off the zstack. Its historical code and I don't feel
            % like changing it.
            darkest_pixels = sort(raw_zstack(:), 'ascend');
            cutoff_ind = round(app.background_fraction * length(darkest_pixels));
            background_value = mean(darkest_pixels(1:cutoff_ind));
            
            app.calcium_zstack = raw_zstack - background_value;
            app.calcium_stack = initial_volumes - background_value;
            
            app.CalciumZDepthSlider.Limits = [1, size(app.calcium_stack, 3)];
            app.calcium_z = max([app.calcium_z, app.CalciumZDepthSlider.Limits(1)]);
            app.calcium_z = min([app.calcium_z, app.CalciumZDepthSlider.Limits(2)]);
            app.CalciumZDepthSlider.Value = app.calcium_z;
            
            % get the cell bodies associated with this stack
            app.current_calcium_ids = app.calcium_cell_info(round(mean(app.stack_ind))).trackIdx;
            app.current_calcium_locations = app.calcium_cell_info(round(mean(app.stack_ind))).rawPoints;
            app.current_calcium_locations = app.current_calcium_locations(~isnan(app.current_calcium_ids), :);
            app.current_calcium_ids = app.current_calcium_ids(~isnan(app.current_calcium_ids), :);
            
            app.calcium_display_lim = [min(app.calcium_stack(:)), max(app.calcium_stack(:))];
            
            app.calculate_display_data();
            app.select_neuron(app.NeuronIndexSpinner.Value);
        end
        
        function [rows,cols] = get_dat_dimensions(~, string)
            %getdatdimension takes a string produced by the whole brain imaging system,
            %which has the image dimensions in the text, and parses the text to extract
            %the rows and columns. string is in the format sCMOS_Frames_U16_1024x512.dat
            %  [rows,cols]=getdatdimensions(string)
        
            xloc= find(string=='x',1,'last');
            dotloc=find(string=='.',1,'last');
            numstart= find(string=='_',1,'last');
            rows=str2double(string(numstart+1:xloc-1));
            cols=str2double(string(xloc+1:dotloc-1));
        end
        
        function [red, green, z_loc] = load_calcium_from_dat(app, data_folder, volumes_to_grab)
            % load_calcium_from_dat  will get all the volumes_to_grab volumes from a
            % calcium recording. All of this code was ripped from the 3dbrain
            % pipeline https://github.com/leiferlab/3dbrain
            
            num_stacks = length(volumes_to_grab);
            
            % z location of the scope
            zWave=app.hi_res_data.Z;
            zWave=gradient(zWave);
            zWave=smooth(zWave,100);
            
            % standard deviation of the image over z
            image_std=app.hi_res_data.imSTD;
            image_std=image_std-mean(image_std);
            image_std(image_std>150)=0;
            [ZSTDcorrplot,lags]=crosscorr(abs(zWave),image_std,30);
            ZSTDcorrplot=smooth(ZSTDcorrplot,3);
            zOffset=lags(ZSTDcorrplot==max(ZSTDcorrplot));
        
            %get image path
            datFileDir=dir([data_folder filesep 'sCMOS_Frames_U16_*']);
            datFile=[data_folder filesep datFileDir.name];
            
            %get image size
            [rows,cols] = app.get_dat_dimensions(datFile);
            nPix=rows*cols;

            % location on the camera of the red (rect1) and green (rect2) image
            rect1 = app.alignments.S2AHiRes.rect1;
            rect2 = app.alignments.S2AHiRes.rect2;
            
            Fid=fopen(datFile);
            
            red_cell = cell(num_stacks, 1);
            green_cell = cell(num_stacks, 1);
            z_loc_cell = cell(num_stacks, 1);
            
            % grab the frames associated with each volume
            for ii = 1:num_stacks
                %select frames to analyze
                hiResIdx=find(app.hi_res_data.stackIdx==volumes_to_grab(ii))+ zOffset;
        
                %do something with status errors!
                status=fseek(Fid,2*(hiResIdx(1))*nPix,-1);
        
                pixelValues=fread(Fid,nPix*(length(hiResIdx)),'uint16',0,'l');
                hiResImage=reshape(pixelValues,rows,cols,length(hiResIdx));
                
                % put each volume into either a cell array
                red_cell{ii}=hiResImage((rect1(2)+1):rect1(4),(1+rect1(1)):rect1(3),:);
                green_cell{ii}=hiResImage((rect1(4)+1):end,(1+rect1(1)):rect1(3),:);
                z_loc_cell{ii} = app.hi_res_data.Z(hiResIdx);
            end
            
            % find the minimum of each dimension in the cell array, crop
            % each frame to the minimum and then turn into a matrix
            image_dim = cell2mat(cellfun(@size, [red_cell; green_cell], 'UniformOutput', false));
            min_dim = min(image_dim, [], 1);
            
            cropped_red = cellfun(@(x) x(1:min_dim(1), 1:min_dim(2), 1:min_dim(3)), red_cell, 'UniformOutput', false);
            cropped_green = cellfun(@(x) x(1:min_dim(1), 1:min_dim(2), 1:min_dim(3)), green_cell, 'UniformOutput', false);
            cropped_z = cellfun(@(x) x(1:min_dim(3)), z_loc_cell, 'UniformOutput', false);
            
            red = cell2mat(permute(cropped_red, [4, 2, 3, 1]));
            green = cell2mat(permute(cropped_green, [4, 2, 3, 1]));
            z_loc = cell2mat(cropped_z');
            
            fclose(Fid);
        end
        
        function calcium_clicked(app, ~, hit)
            % Where did the user click?
            mouse_pos = hit.IntersectionPoint(1:2);
                        
            % Find the closest neuron to the mouse click.
            distance = sqrt(sum((mouse_pos - app.rotated_calcium_cell_locations(:, [1, 2])).^2, 2));
            distance(~app.in_range_calcium_point_inds) = inf;
            [distance_to_cell, temp_neuron_id] = min(distance);
            
            if distance_to_cell <= app.max_click_distance
                clicked_calcium_id = app.current_calcium_ids(temp_neuron_id);
                app.select_neuron(clicked_calcium_id);
            end
        end
        
        function multicolor_clicked(app, ~, hit)
            % Where did the user click?
            mouse_pos = hit.IntersectionPoint(1:2);
            
            % Find the closest neuron to the mouse click.
            distance = sqrt(sum((mouse_pos - app.multicolor_cell_locations(:, [1, 2])).^2, 2));
            distance(~app.in_range_multicolor_point_inds) = inf;
            [distance_to_cell, clicked_multicolor_id] = min(distance);
            
            if (distance_to_cell <= app.max_click_distance) || strcmp(app.last_key_pressed, 'a')
                switch hit.Button
                    case 1 % left click
                        switch app.last_key_pressed
                            case 'a'
                                % add a neuron to the multicolor image
                                cell_pos = [mouse_pos, app.multicolor_z];
                                app.multicolor_cell_locations(end+1, :) = cell_pos;
                                app.add_neuropal_cell(cell_pos);
                                
                                app.assignments(app.NeuronIndexSpinner.Value) = size(app.multicolor_cell_locations, 1);
                                
                            case 's'
                                % instead of assigning, highlight the
                                % calcium neuron that was assigned to the
                                % multicolor neuron
                                assigned_calcium = find(app.assignments==clicked_multicolor_id, 1, 'first');
                                
                                if ~isempty(assigned_calcium)
                                    app.select_neuron(assigned_calcium);
                                end
                                
                            otherwise
                                % assign the multicolor neuron to the
                                % selected calcium neuron
                                
                                % check if the clicked multicolor cell was
                                % previously assigned to another neuron and
                                % unassign it
                                app.assignments(app.assignments==clicked_multicolor_id) = 0;
                                
                                app.assignments(app.NeuronIndexSpinner.Value) = clicked_multicolor_id;
                                
                                app.select_neuron(app.NeuronIndexSpinner.Value);
                        end
                        
                    case 3 % right click
                        switch app.last_key_pressed
                            case 'a'
                                % remove the clicked neuron from the
                                % multicolor image
                                
                                % if any neuron was assigned to that neuron
                                % unassign it
                                app.assignments(app.assignments == clicked_multicolor_id) = 0;
                                app.assignments(app.assignments > clicked_multicolor_id) = app.assignments(app.assignments > clicked_multicolor_id) - 1;
                                % remove the clicked neuron
                                app.multicolor_cell_locations(clicked_multicolor_id, :) = [];
                                app.neuropal_data.neurons.neurons(clicked_multicolor_id) = [];
                            
                            otherwise
                                % unassign the calcium neuron
                                
                                % check if there is a calcium cell selected
                                if app.assignments(app.NeuronIndexSpinner.Value) ~= 0
                                    app.assignments(app.NeuronIndexSpinner.Value) = 0;
                                end
                        end
                end
                
                app.display_images();
            end
        end
        
        function add_neuropal_cell(app, position)
            app.neuropal_data.neurons.neurons(end+1) = copy(app.neuropal_data.neurons.neurons(end));
            app.neuropal_data.neurons.neurons(end).position = position([2, 1, 3]);
            app.neuropal_data.neurons.neurons(end).color = zeros(1, 4);
            app.neuropal_data.neurons.neurons(end).color_readout = zeros(1, 4);
            app.neuropal_data.neurons.neurons(end).baseline = zeros(1, 4);
            app.neuropal_data.neurons.neurons(end).covariance = zeros(3, 3);
            app.neuropal_data.neurons.neurons(end).truncation = 1e-11;
            app.neuropal_data.neurons.neurons(end).aligned_xyzRGB = [];
            app.neuropal_data.neurons.neurons(end).outlier = [];
            app.neuropal_data.neurons.neurons(end).annotation = '';
            app.neuropal_data.neurons.neurons(end).is_annotation_on = nan;
            app.neuropal_data.neurons.neurons(end).annotation_confidence = -1;
            app.neuropal_data.neurons.neurons(end).deterministic_id = [];
            app.neuropal_data.neurons.neurons(end).probabilistic_ids = [];
            app.neuropal_data.neurons.neurons(end).probabilistic_probs = [];
            app.neuropal_data.neurons.neurons(end).rank = [];
            app.neuropal_data.neurons.neurons(end).is_selected = 0;
        end
        
        function calculate_display_data(app)
            % rotate_data  
            % Rotates the image and cell bodies to the angle specified on the
            % knob
            if isempty(app.current_calcium_locations)
                return;
            end
            
            calcium_size = size(app.calcium_stack);
            app.rotated_calcium_stack = app.calcium_stack;
            app.rotated_calcium_zstack = app.calcium_zstack;
            
            % get the center of the image to rotate around
            image_center_x = (1 + calcium_size(2)) / 2;
            image_center_y = (1 + calcium_size(1)) / 2;
            image_center_z = (1 + calcium_size(3)) / 2; 
            image_center = [image_center_x, image_center_y, image_center_z];
            
            centered_points = app.current_calcium_locations - image_center;
            
            % perform any necessary flips
            if app.XFlipButton.Value
                centered_points(:, 1) = -centered_points(:, 1);
                app.rotated_calcium_stack = flip(app.rotated_calcium_stack, 2);
                app.rotated_calcium_zstack = flip(app.rotated_calcium_zstack, 2);
            end
            
            if app.YFlipButton.Value
                centered_points(:, 2) = -centered_points(:, 2);
                app.rotated_calcium_stack = flip(app.rotated_calcium_stack, 1);
                app.rotated_calcium_zstack = flip(app.rotated_calcium_zstack, 1);
            end
            
            if xor(app.XFlipButton.Value, app.YFlipButton.Value)
                centered_points(:, 3) = -centered_points(:, 3);
                app.rotated_calcium_stack = flip(app.rotated_calcium_stack, 3);
            end
            
            if app.image_is_z_flipped
                centered_points(:, 3) = -centered_points(:, 3);
                app.rotated_calcium_stack = flip(app.rotated_calcium_stack, 3);
            end
            
            % rotate the image after all that flipping
            app.rotated_calcium_stack = imrotate(app.rotated_calcium_stack, -app.rotation, 'bilinear');
            app.rotated_calcium_zstack = imrotate(app.rotated_calcium_zstack, -app.rotation, 'bilinear');
            
            rotated_calcium_size = size(app.rotated_calcium_stack);
            rotated_center_x = (1 + rotated_calcium_size(2)) / 2;
            rotated_center_y = (1 + rotated_calcium_size(1)) / 2;
            
            rotated_center = [rotated_center_x, rotated_center_y, image_center_z];
            
            % rotate the points around the image scenter
            point_rotate_matrix = [cosd(app.rotation), -sind(app.rotation), 0; sind(app.rotation), cosd(app.rotation), 0; 0, 0, 1];
            tracked_points_rotated_centered = centered_points * point_rotate_matrix';
            
            app.rotated_calcium_cell_locations = tracked_points_rotated_centered + rotated_center;
        end
        
        function select_neuron(app, neuron_id)
            if neuron_id > app.total_tracked_cells
                app.NeuronIndexSpinner.Value = 1;
                neuron_id = 1;
            end
            
            app.NeuronIndexSpinner.Value = neuron_id;
                                
            switch app.JumpList.Value
                case 'Assigned Neuron'
                    current_neuron_ind = app.current_calcium_ids == neuron_id;
                    
                    if any(current_neuron_ind)
                        app.calcium_z = round(app.rotated_calcium_cell_locations(current_neuron_ind, 3));
                        app.CalciumZDepthSlider.Value = app.calcium_z;
                    end
                    
                    assigned_multicolor_id = app.assignments(neuron_id);
                    if assigned_multicolor_id ~= 0
                        app.multicolor_z = round(app.multicolor_cell_locations(assigned_multicolor_id, 3));
                        app.MulticolorZDepthSlider.Value = app.multicolor_z;
                    end
                    
                case 'None'
                    % do nothing
            end
            
            app.display_images()
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            axis(app.UIAxes1, 'equal');
            axis(app.UIAxes2, 'equal');
            app.stack_ind = app.FrameSlider.Value;
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            app.LoadButton.Text = 'PLEASE WAIT';
            
            app.data_folder = uigetdir(app.config.data_location, 'Select the brainscanner folder');
    
            if all(app.data_folder == 0)
                return;
            end
                        
            % get the information from the high resolution microscope recording
            hiResData_path = fullfile(app.data_folder, 'hiResData.mat');
            app.hi_res_data = load(hiResData_path);
            app.hi_res_data = app.hi_res_data.dataAll;
            alignment_path = fullfile(app.data_folder, 'alignments.mat');
            app.alignments = load(alignment_path);
            app.alignments = app.alignments.alignments;
            
            
            % load in PointStats2 which has all the cell body locations
            point_stats_path = fullfile(app.data_folder, 'PointsStats2.mat');
            
            point_stats_in = load(point_stats_path);
            app.calcium_cell_info = point_stats_in.pointStats2;
            
            % load in the multicolor data
            multicolor_search = dir(fullfile(app.data_folder, 'multicolor*'));
            
            if isempty(multicolor_search)
                error('cannot find the multicolor folder');
            end
                        
            multicolor_search = multicolor_search(1); % take only the first result. Can't handle multiple multicolor folders in one file
            multicolor_folder = fullfile(multicolor_search.folder, multicolor_search.name);
            
            neuropal_file_path = fullfile(multicolor_folder, 'neuropal_data.mat');
            app.neuropal_data_path = fullfile(multicolor_folder, 'neuropal_data_ID.mat');
            
            if exist(app.neuropal_data_path, 'file')
                multicolor_data = load(neuropal_file_path);
                pan_ind = multicolor_data.info.RGBW(4);
                
                % get the panneuronal markers from the full multicolor
                % stack
                app.multicolor_stack  = squeeze(multicolor_data.data(:, :, :, pan_ind));
                app.multicolor_display_lim = [min(app.multicolor_stack(:)), max(app.multicolor_stack(:))];
                
                for ff = 1:size(app.multicolor_stack, 3)
                    app.multicolor_stack(:, :, ff) = imadjust(app.multicolor_stack(:, :, ff), [], [], 0.5);
                end
                
                app.MulticolorZDepthSlider.Limits = [1, size(app.multicolor_stack, 3)];
                app.MulticolorZDepthSlider.Value = round(mean(app.MulticolorZDepthSlider.Limits));
                app.multicolor_z = app.MulticolorZDepthSlider.Value;
                
                % get multicolor cell locations and labels from the
                % neuropal file
                loaded_neuropal_data = load(app.neuropal_data_path);
                % load the cell locations, but use the ones in the
                % assignment file if it exists
                neuropal_cell_locations = cat(1, loaded_neuropal_data.neurons.neurons.position);
                
                if ~isempty(neuropal_cell_locations)
                    % seems to be saved as y, x, z, swap here
                    neuropal_cell_locations = neuropal_cell_locations(:, [2, 1, 3]);
                else
                    warning('neuropal ID file has no saved cell locations');
                end
            else
                error('cannot find neuropal cell id file');
            end
            
            % check if an assignments already exists
            assignments_name = 'calcium_to_multicolor_assignments.mat';
            assignments_path = fullfile(app.data_folder, assignments_name);
            app.total_tracked_cells = max(cat(1, app.calcium_cell_info.trackIdx));
            
            app.FrameSlider.Limits = double([10, app.hi_res_data.stackIdx(end) - 10]);
            app.FrameSpinner.Limits = double([10, app.hi_res_data.stackIdx(end) - 10]);
            
            if exist(assignments_path, 'file')
                update_assignment_labels(app.data_folder);
                loaded_assignments = load(assignments_path);
                
                app.assignments = loaded_assignments.assignments;
                app.neuropal_data = loaded_assignments.neuropal_data;
                app.multicolor_cell_locations = loaded_assignments.multicolor_cell_locations;
                app.rotation = loaded_assignments.gui_settings.rotation;
                app.flip_x = loaded_assignments.gui_settings.flip_x;
                app.flip_y = loaded_assignments.gui_settings.flip_y;
                app.stack_ind = loaded_assignments.gui_settings.stack_ind;
                app.JumpList.Value = loaded_assignments.gui_settings.jump_type;
            else
                app.assignments = zeros(app.total_tracked_cells,1);
                app.multicolor_cell_locations = neuropal_cell_locations;
                app.neuropal_data = loaded_neuropal_data;
                app.rotation = 0;
                app.flip_x = false;
                app.flip_y = false;
                app.stack_ind = 100;
            end
            
            % set default values
            app.DisplayMaxIntensityButton.Value = true;
            app.RotateKnob.Value = app.rotation;
            app.XFlipButton.Value = app.flip_x;
            app.YFlipButton.Value = app.flip_y;
            app.FrameSlider.Value = app.stack_ind;
            app.FrameSpinner.Value = app.stack_ind;
            
            app.get_calcium_stack();
            app.calculate_display_data();
            app.select_neuron(1);
            
            app.LoadButton.Text = 'Load';
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            app.SaveButton.Text = 'PLEASE WAIT';
            pause(0.1);
            
            human_labels = {app.neuropal_data.neurons.neurons.annotation}';
            auto_labels = {app.neuropal_data.neurons.neurons.deterministic_id}';
            user_labeled = cat(1, app.neuropal_data.neurons.neurons.annotation_confidence)==1;
            auto_confidence = cat(1, app.neuropal_data.neurons.neurons.annotation_confidence);
            
            calcium_locations_in_multicolor = nan(app.total_tracked_cells, 3);
            human_labels_out = cell(app.total_tracked_cells, 1);
            auto_labels_out = cell(app.total_tracked_cells, 1);
            human_labels_out(:) = {''};
            auto_labels_out(:) = {''};
            user_labeled_out = false(app.total_tracked_cells, 1);
            auto_confidence_out = zeros(app.total_tracked_cells, 1)-1;
            
            % restrict all the label data to the assigned cells
            for cc = 1:app.total_tracked_cells
                this_assignment = app.assignments(cc);
                
                if this_assignment ~= 0
                    calcium_locations_in_multicolor(cc, :) = app.multicolor_cell_locations(this_assignment, :);
                    human_labels_out{cc} = human_labels{this_assignment};
                    auto_labels_out{cc} = auto_labels{this_assignment};
                    user_labeled_out(cc) = user_labeled(this_assignment);
                    auto_confidence_out(cc) = auto_confidence(this_assignment);
                end
            end
            
            save_struct.assignments = app.assignments;
            save_struct.multicolor_cell_locations = app.multicolor_cell_locations;
            save_struct.neuropal_data = app.neuropal_data;
            save_struct.calcium_locations_in_multicolor = calcium_locations_in_multicolor;
            save_struct.labels.human_labels = human_labels_out;
            save_struct.labels.auto_labels = auto_labels_out;
            save_struct.labels.user_labeled = user_labeled_out;
            save_struct.labels.auto_confidence = auto_confidence_out;
            
            % save the cosmetic adjustments for ease
            save_struct.gui_settings.rotation = app.rotation;
            save_struct.gui_settings.flip_x = app.flip_x;
            save_struct.gui_settings.flip_y = app.flip_y;
            save_struct.gui_settings.stack_ind = app.stack_ind;
            save_struct.gui_settings.jump_type = app.JumpList.Value;
            
            % save the neuropal data but without the unassigned neurons
            % remove all the neurons in the neuropal file that are
            % unassigned
            % because matlab doesn't let us copy handle classes we either
            % need to modify the base class or save/load here to make a
            % copy
            output_neuropal = app.neuropal_data;
            neuropal_copy_path = [app.neuropal_data_path(1:end-3) '_COPY.mat'];
            save(neuropal_copy_path, '-struct', 'output_neuropal');
            output_neuropal = load(neuropal_copy_path);
            delete(neuropal_copy_path);
            
            output_neuropal.neurons.neurons = output_neuropal.neurons.neurons(app.assigned_multicolor);
            
            save(app.neuropal_data_path, '-struct', 'output_neuropal');
            
            % save the assignment data
            save_path = fullfile(app.data_folder, 'calcium_to_multicolor_assignments.mat');
            save(save_path, '-struct', 'save_struct');

            app.SaveButton.Text = 'Save';
        end

        % Value changing function: FrameSlider
        function FrameSliderValueChanging(app, event)
            app.stack_ind = round(event.Value);
            app.FrameSpinner.Value = app.stack_ind;
            app.get_calcium_stack();
            app.display_images();
        end

        % Value changing function: RotateKnob
        function RotateKnobValueChanging(app, event)
            app.rotation = event.Value;
            app.calculate_display_data();
            app.display_images();
        end

        % Value changed function: XFlipButton
        function XFlipButtonValueChanged(app, event)
            app.flip_x = app.XFlipButton.Value;
            app.calculate_display_data();
            app.display_images();
        end

        % Value changed function: YFlipButton
        function YFlipButtonValueChanged(app, event)
            app.flip_y = app.YFlipButton.Value;
            app.calculate_display_data();
            app.display_images();
        end

        % Value changing function: FrameSpinner
        function FrameSpinnerValueChanging(app, event)
            new_frame_ind = event.Value;
            app.FrameSlider.Value = new_frame_ind;
            app.stack_ind = new_frame_ind;
            app.get_calcium_stack();
            app.display_images();
        end

        % Value changing function: CalciumZDepthSlider
        function CalciumZDepthSliderValueChanging(app, event)
            app.calcium_z = round(event.Value);
            app.calculate_display_data();
            app.display_images();
        end

        % Value changing function: MulticolorZDepthSlider
        function MulticolorZDepthSliderValueChanging(app, event)
            app.multicolor_z = round(event.Value);
            app.display_images();
        end

        % Value changed function: CalciumZDepthSlider
        function CalciumZDepthSliderValueChanged(app, event)
            app.CalciumZDepthSlider.Value = round(app.CalciumZDepthSlider.Value);
        end

        % Value changed function: MulticolorZDepthSlider
        function MulticolorZDepthSliderValueChanged(app, event)
            app.MulticolorZDepthSlider.Value = round(app.MulticolorZDepthSlider.Value);
        end

        % Value changed function: NeuronIndexSpinner
        function NeuronIndexSpinnerValueChanged(app, event)
            app.select_neuron(event.Value);
        end

        % Value changed function: DisplayMaxIntensityButton
        function DisplayMaxIntensityButtonValueChanged(app, event)
            app.display_images();
        end

        % Key press function: UIFigure
        function UIFigureKeyPress(app, event)
            app.last_key_pressed = event.Key;
            app.CurrentKeyLabelOutput.Text = event.Key;
        end

        % Key release function: UIFigure
        function UIFigureKeyRelease(app, event)
            app.last_key_pressed = '';
            app.CurrentKeyLabelOutput.Text = 'none';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.UIFigure.KeyReleaseFcn = createCallbackFcn(app, @UIFigureKeyRelease, true);
            app.UIAxes1 = uiaxes(app.UIFigure);
            title(app.UIAxes1, '')
            xlabel(app.UIAxes1, '')
            ylabel(app.UIAxes1, '')
            app.UIAxes1.XTick = [];
            app.UIAxes1.YTick = [];
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, '')
            xlabel(app.UIAxes2, '')
            ylabel(app.UIAxes2, '')
            app.UIAxes2.XTick = [];
            app.UIAxes2.YTick = [];
            app.FrameSliderLabel = uilabel(app.UIFigure);
            app.FrameSliderLabel.Text = 'Frame';
            app.FrameSlider = uislider(app.UIFigure);
            app.FrameSlider.Limits = [10 1000];
            app.FrameSlider.ValueChangingFcn = createCallbackFcn(app, @FrameSliderValueChanging, true);
            app.FrameSlider.Value = 100;
            app.SaveButton = uibutton(app.UIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.LoadButton = uibutton(app.UIFigure, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.RotateKnobLabel = uilabel(app.UIFigure);
            app.RotateKnobLabel.Text = 'Rotate';
            app.RotateKnob = uiknob(app.UIFigure, 'continuous');
            app.RotateKnob.Limits = [-180 180];
            app.RotateKnob.ValueChangingFcn = createCallbackFcn(app, @RotateKnobValueChanging, true);
            app.XFlipButton = uibutton(app.UIFigure, 'state');
            app.XFlipButton.ValueChangedFcn = createCallbackFcn(app, @XFlipButtonValueChanged, true);
            app.XFlipButton.Text = 'X';
            app.YFlipButton = uibutton(app.UIFigure, 'state');
            app.YFlipButton.ValueChangedFcn = createCallbackFcn(app, @YFlipButtonValueChanged, true);
            app.YFlipButton.Text = 'Y';
            app.FlipLabel = uilabel(app.UIFigure);
            app.FlipLabel.Text = 'Flip';
            app.FrameSpinner = uispinner(app.UIFigure);
            app.FrameSpinner.ValueChangingFcn = createCallbackFcn(app, @FrameSpinnerValueChanging, true);
            app.FrameSpinner.Limits = [10 1000];
            app.FrameSpinner.RoundFractionalValues = 'on';
            app.FrameSpinner.Value = 100;
            app.ImageSizeLabel = uilabel(app.UIFigure);
            app.ImageSizeLabel.Text = 'Image size is:';
            app.ImageSizeOutputLabel = uilabel(app.UIFigure);
            app.ImageSizeOutputLabel.Text = '<image size here>';
            app.NumCellsIdLabel = uilabel(app.UIFigure);
            app.NumCellsIdLabel.Text = '# cells in volume:';
            app.NumCellsIdOutputLabel = uilabel(app.UIFigure);
            app.NumCellsIdOutputLabel.Text = '<# cells here>';
            app.CalciumZDepthSliderLabel = uilabel(app.UIFigure);
            app.CalciumZDepthSliderLabel.Text = 'Calcium Z Depth';
            app.CalciumZDepthSlider = uislider(app.UIFigure);
            app.CalciumZDepthSlider.Limits = [1 32];
            app.CalciumZDepthSlider.ValueChangedFcn = createCallbackFcn(app, @CalciumZDepthSliderValueChanged, true);
            app.CalciumZDepthSlider.ValueChangingFcn = createCallbackFcn(app, @CalciumZDepthSliderValueChanging, true);
            app.CalciumZDepthSlider.MinorTicks = [];
            app.CalciumZDepthSlider.Value = 16;
            app.MulticolorZDepthSliderLabel = uilabel(app.UIFigure);
            app.MulticolorZDepthSliderLabel.Text = 'Multicolor Z Depth';
            app.MulticolorZDepthSlider = uislider(app.UIFigure);
            app.MulticolorZDepthSlider.Limits = [1 32];
            app.MulticolorZDepthSlider.ValueChangedFcn = createCallbackFcn(app, @MulticolorZDepthSliderValueChanged, true);
            app.MulticolorZDepthSlider.ValueChangingFcn = createCallbackFcn(app, @MulticolorZDepthSliderValueChanging, true);
            app.MulticolorZDepthSlider.MinorTicks = [];
            app.MulticolorZDepthSlider.Value = 16;
            app.NeuronIndexLabel = uilabel(app.UIFigure);
            app.NeuronIndexLabel.Text = 'Neuron Index';
            app.NeuronIndexSpinner = uispinner(app.UIFigure);
            app.NeuronIndexSpinner.Limits = [1 Inf];
            app.NeuronIndexSpinner.ValueChangedFcn = createCallbackFcn(app, @NeuronIndexSpinnerValueChanged, true);
            app.NeuronIndexSpinner.Value = 1;
            app.SelectingneuronjumpstoDropDownLabel = uilabel(app.UIFigure);
            app.SelectingneuronjumpstoDropDownLabel.Text = 'Selecting neuron jumps to';
            app.JumpList = uidropdown(app.UIFigure);
            app.JumpList.Items = {'Assigned Neuron', 'None'};
            app.JumpList.Value = 'Assigned Neuron';
            app.DisplayMaxIntensityButton = uibutton(app.UIFigure, 'state');
            app.DisplayMaxIntensityButton.ValueChangedFcn = createCallbackFcn(app, @DisplayMaxIntensityButtonValueChanged, true);
            app.DisplayMaxIntensityButton.Text = 'Display Max Intensity';
            app.DisplayMaxIntensityButton.Value = true;
            app.AssignedNeuronsTable = uitable(app.UIFigure);
            app.AssignedNeuronsTable.ColumnName = {'Assigned'; 'Total'};
            app.AssignedNeuronsTable.RowName = {};
            app.CurrentKeyLabel = uilabel(app.UIFigure);
            app.CurrentKeyLabel.Text = 'Current Pressed Key:';
            app.CurrentKeyLabelOutput = uilabel(app.UIFigure);
            app.CurrentKeyLabelOutput.Text = 'none';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = assign_calcium_to_multicolor

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end