classdef check_cell_assignment < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        UIAxes1                      matlab.ui.control.UIAxes
        UIAxes2                      matlab.ui.control.UIAxes
        NeuronIDSpinnerLabel         matlab.ui.control.Label
        NeuronIDSpinner              matlab.ui.control.Spinner
        LoadButton                   matlab.ui.control.Button
        CalciumLabel                 matlab.ui.control.Label
        CalciumSlider                matlab.ui.control.Slider
        MulticolorLabel              matlab.ui.control.Label
        MulticolorSlider             matlab.ui.control.Slider
        RotateCalciumImageKnobLabel  matlab.ui.control.Label
        RotateCalciumImageKnob       matlab.ui.control.Knob
        MetricTable                  matlab.ui.control.Table
        CalciumInstructionLabel      matlab.ui.control.Label
        MulticolorInstructionLabel   matlab.ui.control.Label
        SaveButton                   matlab.ui.control.Button
        FlipLabel                    matlab.ui.control.Label
        XButton                      matlab.ui.control.StateButton
        YButton                      matlab.ui.control.StateButton
        SelectingneuronjumpstoDropDownLabel  matlab.ui.control.Label
        JumpList                     matlab.ui.control.DropDown
    end

    
    properties (Access = private)
        data_path;
        
        alignment;
        
        calcium_volume;
        calcium_volume_rotated;
        calcium_size;
        calcium_scale;
        calcium_scale_rotated;
        calcium_clim;
        calcium_points;
        flip_cells_z = false;
        
        tracked_points;
        tracked_points_rotated;
        
        multicolor_volume;
        multicolor_size;
        multicolor_scale;
        multicolor_clim
        multicolor_points;
        
        calcium_to_multicolor_assignments;
        tracked_to_multicolor_assignments;
        tracked_to_multicolor_assignments_user_adjusted;
        
        user_assigned_cells;
        
        cell_z_range = 2; % distance in microns to display cells
        marker_size = 25;
    end
    
    methods (Access = private)
        function rotate_data(app, degrees_rotate)
            % rotate_data  Rotates the image to the angle specified on the
            % knob
            
            if nargin < 2
                % rotate the calcium image using the knob value
                degrees_rotate = app.RotateCalciumImageKnob.Value;
            end
            
            % get the center of the image to rotate around
            image_center_x = mean((1:app.calcium_size(1))*app.calcium_scale(1));
            image_center_y = mean((1:app.calcium_size(2))*app.calcium_scale(2)); 
            image_center_z = mean((1:app.calcium_size(3))*app.calcium_scale(3));
            image_center = [image_center_x, image_center_y, image_center_z];
            
            app.calcium_volume_rotated = imrotate(app.calcium_volume, -degrees_rotate, 'bilinear');

            % first change the xy scale due to rotation
            % currently does't work, it doesn't change scale how I would
            % expect. Just keep the scale the same for now, this will work
            % as long as app.calcium_scale(1) = app.calcium_scale(2)
%             x_scale = max(abs([app.calcium_scale(1)*cosd(-degrees_rotate), app.calcium_scale(2)*cosd(-degrees_rotate + 90)]));
%             y_scale = max(abs([app.calcium_scale(1)*sind(-degrees_rotate), app.calcium_scale(2)*sind(-degrees_rotate + 90)]));

            x_scale = app.calcium_scale(1);
            y_scale = app.calcium_scale(2);
            z_scale = app.calcium_scale(3);
            
            app.calcium_scale_rotated = [x_scale, y_scale, z_scale]';
            
            rotated_center_x = mean((1:size(app.calcium_volume_rotated,2))*app.calcium_scale_rotated(1));
            rotated_center_y = mean((1:size(app.calcium_volume_rotated,1))*app.calcium_scale_rotated(2));
            rotated_center_z = mean((1:size(app.calcium_volume_rotated,3))*app.calcium_scale_rotated(3));
            
            rotated_center = [rotated_center_x, rotated_center_y, rotated_center_z];
            
            % rotate the points around the image scenter
            point_rotate_matrix = [cosd(degrees_rotate), -sind(degrees_rotate) 0; sind(degrees_rotate), cosd(degrees_rotate) 0; 0 0 1];
            tracked_points_rotated_centered = (app.tracked_points - image_center) * point_rotate_matrix';
            
            if app.XButton.Value
                tracked_points_rotated_centered(:, 1) = -tracked_points_rotated_centered(:, 1);
                app.calcium_volume_rotated = flip(app.calcium_volume_rotated, 2);
                
                tracked_points_rotated_centered(:, 3) = -tracked_points_rotated_centered(:, 3);
                app.calcium_volume_rotated = flip(app.calcium_volume_rotated, 3);
            end
            
            if app.YButton.Value
                tracked_points_rotated_centered(:, 2) = -tracked_points_rotated_centered(:, 2);
                app.calcium_volume_rotated = flip(app.calcium_volume_rotated, 1);
                
                tracked_points_rotated_centered(:, 3) = -tracked_points_rotated_centered(:, 3);
                app.calcium_volume_rotated = flip(app.calcium_volume_rotated, 3);
            end
            
            if app.flip_cells_z
                tracked_points_rotated_centered(:, 3) = -tracked_points_rotated_centered(:, 3);
            end
            
            app.tracked_points_rotated = tracked_points_rotated_centered + rotated_center;
        end
        
        function plot_slice(app, calcium_slice, multicolor_slice)
            % plot_slice  will find and display the current frame or
            % "slice" from the multicolor and calcium volumes
            
            chosen_marker_size = app.marker_size;
            
            % update the table of algo accuracy
            app.update_table();
            
            % choose which slice to rotate and display
            if nargin < 2
                calcium_slice = round(app.CalciumSlider.Value);
            end
            
            if nargin < 3
                multicolor_slice = round(app.MulticolorSlider.Value);
            end
            
            % find which cells are in the current frame
            chosen_multicolor_points = abs(app.multicolor_points(:, 3) - multicolor_slice * app.multicolor_scale(3)) < app.cell_z_range;
            chosen_tracked_points = abs(app.tracked_points_rotated(:, 3) - calcium_slice * app.calcium_scale(3)) < app.cell_z_range;

            
            % find which cell is currently selected
            selected_calcium_cell_id = app.NeuronIDSpinner.Value;
            selected_calcium_cell = app.tracked_points_rotated(selected_calcium_cell_id, :);
            selected_multicolor_cell_id = app.tracked_to_multicolor_assignments_user_adjusted(selected_calcium_cell_id);
            
            selected_multicolor_cell = [];
            if selected_multicolor_cell_id ~= 0
                selected_multicolor_cell = app.multicolor_points(selected_multicolor_cell_id, :);
            end

            % get the x y coordinates of the image to show
            calcium_x = (1:size(app.calcium_volume_rotated,2))'*app.calcium_scale_rotated(1);
            calcium_y = (1:size(app.calcium_volume_rotated,1))'*app.calcium_scale_rotated(2);
            
            multicolor_x = (1:app.multicolor_size(2))'*app.multicolor_scale(1);
            multicolor_y = (1:app.multicolor_size(1))'*app.multicolor_scale(2);
            
            %% plot calcium recording image
            imagesc(app.UIAxes1, calcium_x, calcium_y, app.calcium_volume_rotated(:, :, calcium_slice));
            hold(app.UIAxes1, 'on');
            
            % plot the currently selected cell
            scatter(app.UIAxes1, selected_calcium_cell(:, 1), selected_calcium_cell(:, 2), chosen_marker_size, 'X', 'r');
            
            % plot all cell locations
            tracked_cells_in_frame = app.tracked_points_rotated(chosen_tracked_points, :);
            tracked_cells_handle = scatter(app.UIAxes1, tracked_cells_in_frame(:, 1), tracked_cells_in_frame(:, 2), chosen_marker_size, 'O', 'b');
            
            % plot cells that have been user assigned
            tracked_cells_in_frame_assigned = app.tracked_points_rotated(chosen_tracked_points & app.user_assigned_cells, :);
            labeled_tracked_cells_handle = scatter(app.UIAxes1, tracked_cells_in_frame_assigned(:, 1), tracked_cells_in_frame_assigned(:, 2), chosen_marker_size, 'O', 'g');
            
            % set up mouse click callbacks
            tracked_cells_handle.ButtonDownFcn = {@app.calcium_clicked};
            labeled_tracked_cells_handle.ButtonDownFcn = {@app.calcium_clicked};
            
            hold(app.UIAxes1, 'off');
            caxis(app.UIAxes1, app.calcium_clim);
            colormap(app.UIAxes1, gray);
            
            %% plot multicolor recording image
            imagesc(app.UIAxes2, multicolor_x, multicolor_y, app.multicolor_volume(:, :, multicolor_slice));
            hold(app.UIAxes2, 'on');
            
            % plot the currently selected cell
            if ~isempty(selected_multicolor_cell)
                scatter(app.UIAxes2, selected_multicolor_cell(1), selected_multicolor_cell(2), chosen_marker_size, 'X', 'r')
            end
            
            % plot all cell locations
            multicolor_cells_in_frame = app.multicolor_points(chosen_multicolor_points, :);
            multicolor_cells_handle = scatter(app.UIAxes2, multicolor_cells_in_frame(:, 1), multicolor_cells_in_frame(:, 2), chosen_marker_size, 'O', 'b');
            
            % plot cells that have been user assigned
            labeled_assignments = app.tracked_to_multicolor_assignments_user_adjusted(app.user_assigned_cells);
            labeled_assignments = labeled_assignments(labeled_assignments ~= 0);
            multicolor_cells_labeled = app.multicolor_points(labeled_assignments, :);
            multicolor_cells_in_frame_labeled = abs(multicolor_cells_labeled(:, 3) - multicolor_slice * app.multicolor_scale(3)) < app.cell_z_range;
            labeled_multicolor_cells_handle = scatter(app.UIAxes2, multicolor_cells_labeled(multicolor_cells_in_frame_labeled, 1), multicolor_cells_labeled(multicolor_cells_in_frame_labeled, 2), chosen_marker_size, 'O', 'g');

            % set up mouse click callbacks
            multicolor_cells_handle.ButtonDownFcn = {@app.multicolor_clicked};
            labeled_multicolor_cells_handle.ButtonDownFcn = {@app.multicolor_clicked};
            
            hold(app.UIAxes2, 'off');
            caxis(app.UIAxes2, app.multicolor_clim);
            colormap(app.UIAxes2, gray);
        end
        
        function calcium_clicked(app, ~, hit)
            % Where did the user click?
            mouse_pos = hit.IntersectionPoint(1:2);
            
            mouse_pos = [mouse_pos, app.CalciumSlider.Value .* app.calcium_scale_rotated(3)];
            
            % Find the closest neuron to the mouse click.
            positions = app.tracked_points_rotated;
            distance = sqrt(sum((positions-mouse_pos).^2,2));
            [~, neuron_id] = min(distance);
            
            app.select_neuron(neuron_id);
            app.plot_slice();
        end
        
        function multicolor_clicked(app, ~, hit)
            switch hit.Button
                case 1 % left click
                    % Where did the user click?
                    mouse_pos = hit.IntersectionPoint(1:2);
                    
                    mouse_pos = [mouse_pos, app.MulticolorSlider.Value .* app.multicolor_scale(3)];
                    
                    % Find the closest neuron to the mouse click.
                    positions = app.multicolor_points;
                    distance = sqrt(sum((positions-mouse_pos).^2,2));
                    [~, neuron_id] = min(distance);
            
                    % check if the neuron is already assigned and unassign
                    % it
                    for nn = 1:length(app.tracked_to_multicolor_assignments_user_adjusted)
                        if app.tracked_to_multicolor_assignments_user_adjusted(nn) == neuron_id
                            app.tracked_to_multicolor_assignments_user_adjusted(nn) = 0;
                            app.user_assigned_cells(nn) = false;
                        end
                    end
                    
                    app.tracked_to_multicolor_assignments_user_adjusted(app.NeuronIDSpinner.Value) = neuron_id;


                case 3 % right click
                    app.tracked_to_multicolor_assignments_user_adjusted(app.NeuronIDSpinner.Value) = 0;
            end
            
            app.user_assigned_cells(app.NeuronIDSpinner.Value) = true;
            app.plot_slice();
        end
        
        function select_neuron(app, neuron_id)
            while any(isnan(app.tracked_points_rotated(neuron_id, :)))
                neuron_id = neuron_id + 1;
            end
            
            % set neuron_id
            app.NeuronIDSpinner.Value = neuron_id;
            
            jump_value = app.JumpList.Value;
            
            if ~strcmp(jump_value, 'None')
                % get closest calcium slice to the currently selected cell
                this_calcium_cell = app.tracked_points_rotated(neuron_id, :);
                best_slice_calcium = round(this_calcium_cell(3) / app.calcium_scale(3));
                
                % make sure the best slice is in the range of the volume
                best_slice_calcium(best_slice_calcium > app.calcium_size(3)) = app.calcium_size(3);
                best_slice_calcium(best_slice_calcium < 1) = 1;
                
                % get the closest multicolor slice to the selected calcium cell
                if (app.tracked_to_multicolor_assignments_user_adjusted(neuron_id) ~= 0) && strcmp(jump_value, 'Assigned Neuron')
                    this_multicolor_cell = app.multicolor_points(app.tracked_to_multicolor_assignments_user_adjusted(neuron_id), :);
                    best_slice_multicolor = round(this_multicolor_cell(3) / app.multicolor_scale(3));
                else
                    mean_z_calcium = nanmean(app.tracked_points_rotated(:, 3) / app.calcium_scale(3));
                    mean_z_multicolor = mean(app.multicolor_points(:, 3) / app.multicolor_scale(3));
                    approximate_z_location = this_calcium_cell(3) / app.calcium_scale(3) - mean_z_calcium + mean_z_multicolor;
                    best_slice_multicolor = round(approximate_z_location);
                end
                
                % make sure best_slice_multicolor is in range
                best_slice_multicolor(best_slice_multicolor > app.multicolor_size(3)) = app.multicolor_size(3);
                best_slice_multicolor(best_slice_multicolor < 1) = 1;
                
                % set the slider to the correct slice
                app.CalciumSlider.Value = best_slice_calcium;
                app.MulticolorSlider.Value = best_slice_multicolor;
            end
        end
        
        function update_table(app)
            % recaculate labeling accuracy based on human labels
            row_names = {'% labeled', '% assigned correct', '% unassigned correct', 'overall accuracy'}';

            percent_auto_assigned = mean(app.tracked_to_multicolor_assignments ~= 0)*100;
            
            % find neurons that were correctly assigned ids by the auto
            % program
            auto_assigned_tracked_neuron_bool = app.tracked_to_multicolor_assignments ~= 0;
            auto_assigned_tracked_neurons = app.tracked_to_multicolor_assignments(auto_assigned_tracked_neuron_bool);
            user_assigned_tracked_neurons = app.tracked_to_multicolor_assignments_user_adjusted(auto_assigned_tracked_neuron_bool);
            percent_correctly_assigned = 100*mean(auto_assigned_tracked_neurons == user_assigned_tracked_neurons);
            
            % find neurons that were correctly *un*assigned ids by the auto
            % program
            auto_unassigned_tracked_neuron_bool = app.tracked_to_multicolor_assignments == 0;
            auto_unassigned_tracked_neurons = app.tracked_to_multicolor_assignments(auto_unassigned_tracked_neuron_bool);
            user_unassigned_tracked_neurons = app.tracked_to_multicolor_assignments_user_adjusted(auto_unassigned_tracked_neuron_bool);
            percent_correctly_unassigned = 100*mean(auto_unassigned_tracked_neurons == user_unassigned_tracked_neurons);

            percent_user_assigned = mean(app.user_assigned_cells)*100;
            
            overall_accuracy = nansum([percent_correctly_assigned * mean(auto_assigned_tracked_neuron_bool), percent_correctly_unassigned * mean(auto_unassigned_tracked_neuron_bool)]);
            
            auto_assigned = [round(percent_auto_assigned), round(percent_correctly_assigned), round(percent_correctly_unassigned), round(overall_accuracy)]';
            user_assigned = [floor(percent_user_assigned), 0, 0, 0]';
            metric_table_data = table(auto_assigned, user_assigned, 'RowNames', row_names);
            app.MetricTable.Data = metric_table_data;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            axis(app.UIAxes1, 'equal');
            axis(app.UIAxes2, 'equal');
        end

        % Value changed function: NeuronIDSpinner
        function NeuronIDSpinnerValueChanged(app, event)
            app.select_neuron(event.Value);
            app.plot_slice();
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            config = get_config();
            
            % get data location
            calcium_folder = uigetdir(config.data_location, 'Select the brainscanner folder');
            
            if all(calcium_folder == 0)
                return;
            end
            
            multicolor_search = dir(fullfile(calcium_folder, 'multicolor*'));
            multicolor_search = multicolor_search(1); % take only the first result. Can't handle multiple multicolor folders in one file
            multicolor_folder = fullfile(multicolor_search.folder, multicolor_search.name);

            app.data_path = fullfile(calcium_folder, 'calcium_to_multicolor_alignment.mat');
            
            
            %% load the calcium data
            calcium_in = load(fullfile(calcium_folder, 'calcium_data_average_stack.mat'));
            app.calcium_volume = calcium_in.data(:, :, :, 4);
            app.calcium_volume_rotated = app.calcium_volume;
            app.calcium_size = size(app.calcium_volume);
            app.calcium_clim = [min(app.calcium_volume(:)) max(app.calcium_volume(:))];
            app.flip_cells_z = calcium_in.flip_cells_z;
            
            
            %% load the multicolor data
            multicolor_in = load(fullfile(multicolor_folder, 'neuropal_data.mat'));
            app.multicolor_volume = multicolor_in.data(:, :, :, 4);
            app.multicolor_size = size(app.multicolor_volume);
            app.multicolor_clim = [min(app.multicolor_volume(:)) max(app.multicolor_volume(:))];

            %% load assignment data
            app.alignment = load(app.data_path);
            
            % grab cell_locations
            app.multicolor_points = app.alignment.locations.multicolor_cell_locations;
            app.tracked_points = app.alignment.locations.tracked_cell_locations;
            app.tracked_points_rotated = app.tracked_points;
            
            % grab scale
            app.calcium_scale = app.alignment.locations.calcium_scale;
            app.multicolor_scale = app.alignment.locations.multicolor_scale;

            % grab assignments
            app.tracked_to_multicolor_assignments = app.alignment.assignments.tracked_to_multicolor_assignments;
            app.tracked_to_multicolor_assignments_user_adjusted = app.alignment.assignments.tracked_to_multicolor_assignments_user_adjusted;
            app.user_assigned_cells = app.alignment.assignments.user_assigned_cells;
            
            % set the gui up
            app.RotateCalciumImageKnob.Value = app.alignment.assignments.rotation;
            app.XButton.Value = app.alignment.assignments.flipX;
            app.YButton.Value = app.alignment.assignments.flipY;
            app.JumpList.Value = app.alignment.assignments.jump_value;
            
            app.NeuronIDSpinner.Limits = [1, length(app.tracked_to_multicolor_assignments_user_adjusted)];
            app.CalciumSlider.Limits = [1, size(app.calcium_volume, 3)];
            app.MulticolorSlider.Limits = [1, size(app.multicolor_volume, 3)];
            
            app.update_table();
            app.rotate_data();
            app.select_neuron(1);
            app.plot_slice();
        end

        % Value changed function: CalciumSlider
        function CalciumSliderValueChanged(app, event)
            app.CalciumSlider.Value = round(event.Value);
        end

        % Value changed function: MulticolorSlider
        function MulticolorSliderValueChanged(app, event)
            app.MulticolorSlider.Value = round(event.Value);
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            app.SaveButton.Text = 'PLEASE WAIT';
            pause(0.1);
            
            output_struct = app.alignment;
            output_struct.assignments.tracked_to_multicolor_assignments_user_adjusted = app.tracked_to_multicolor_assignments_user_adjusted;
            output_struct.assignments.user_assigned_cells = app.user_assigned_cells;
            output_struct.assignments.rotation = app.RotateCalciumImageKnob.Value;
            output_struct.assignments.flipX = app.XButton.Value;
            output_struct.assignments.flipY = app.YButton.Value;
            output_struct.assignments.jump_value = app.JumpList.Value;
            
            save(app.data_path, '-struct', 'output_struct');
            
            disp('Saved!');
            
            app.SaveButton.Text = 'Save';
        end

        % Value changed function: XButton
        function XButtonValueChanged(app, event)
            app.rotate_data();
            app.plot_slice();
        end

        % Value changed function: YButton
        function YButtonValueChanged(app, event)
            app.rotate_data();
            app.plot_slice();
        end

        % Value changing function: CalciumSlider
        function CalciumSliderValueChanging(app, event)
            app.plot_slice(round(event.Value), round(app.MulticolorSlider.Value));
        end

        % Value changing function: MulticolorSlider
        function MulticolorSliderValueChanging(app, event)
            app.plot_slice(round(app.CalciumSlider.Value), round(event.Value));
        end

        % Value changing function: RotateCalciumImageKnob
        function RotateCalciumImageKnobValueChanging(app, event)
            app.rotate_data(event.Value);
            app.plot_slice();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes1 = uiaxes(app.UIFigure);
            title(app.UIAxes1, '')
            xlabel(app.UIAxes1, '')
            ylabel(app.UIAxes1, '')
            app.UIAxes1.XTick = [];
            app.UIAxes1.YTick = [];
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, '')
            xlabel(app.UIAxes2, '')
            ylabel(app.UIAxes2, '')
            app.UIAxes2.XTick = [];
            app.UIAxes2.YTick = [];
            app.NeuronIDSpinnerLabel = uilabel(app.UIFigure);
            app.NeuronIDSpinnerLabel.Text = 'Neuron ID';
            app.NeuronIDSpinner = uispinner(app.UIFigure);
            app.NeuronIDSpinner.Limits = [1 Inf];
            app.NeuronIDSpinner.ValueChangedFcn = createCallbackFcn(app, @NeuronIDSpinnerValueChanged, true);
            app.NeuronIDSpinner.Value = 1;
            app.LoadButton = uibutton(app.UIFigure, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.CalciumLabel = uilabel(app.UIFigure);
            app.CalciumLabel.Text = 'Calcium';
            app.CalciumSlider = uislider(app.UIFigure);
            app.CalciumSlider.Limits = [1 32];
            app.CalciumSlider.ValueChangedFcn = createCallbackFcn(app, @CalciumSliderValueChanged, true);
            app.CalciumSlider.ValueChangingFcn = createCallbackFcn(app, @CalciumSliderValueChanging, true);
            app.CalciumSlider.MinorTicks = [];
            app.CalciumSlider.Value = 1;
            app.MulticolorLabel = uilabel(app.UIFigure);
            app.MulticolorLabel.Text = 'Multicolor';
            app.MulticolorSlider = uislider(app.UIFigure);
            app.MulticolorSlider.Limits = [1 32];
            app.MulticolorSlider.ValueChangedFcn = createCallbackFcn(app, @MulticolorSliderValueChanged, true);
            app.MulticolorSlider.ValueChangingFcn = createCallbackFcn(app, @MulticolorSliderValueChanging, true);
            app.MulticolorSlider.MinorTicks = [];
            app.MulticolorSlider.Value = 1;
            app.RotateCalciumImageKnobLabel = uilabel(app.UIFigure);
            app.RotateCalciumImageKnobLabel.Text = 'Rotate Calcium Image';
            app.RotateCalciumImageKnob = uiknob(app.UIFigure, 'continuous');
            app.RotateCalciumImageKnob.Limits = [-180 180];
            app.RotateCalciumImageKnob.ValueChangingFcn = createCallbackFcn(app, @RotateCalciumImageKnobValueChanging, true);
            app.MetricTable = uitable(app.UIFigure);
            app.MetricTable.ColumnName = {'Auto Assigned (%)'; 'Human Assigned (%)'};
            app.MetricTable.RowName = {'labeled'; 'assigned correct'; 'unassigned correct'; 'overall accuracy'};
            app.MetricTable.ColumnSortable = false;
            app.MetricTable.ColumnEditable = false;
            app.CalciumInstructionLabel = uilabel(app.UIFigure);
            app.CalciumInstructionLabel.Text = 'Click calcium cells to select that cell';
            app.MulticolorInstructionLabel = uilabel(app.UIFigure);
            app.MulticolorInstructionLabel.Text = {'Left click multicolor cells to change or confirm assignment'; 'Right click on any blue marker to mark as unassigned'};
            app.SaveButton = uibutton(app.UIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.FlipLabel = uilabel(app.UIFigure);
            app.FlipLabel.Text = 'Flip';
            app.XButton = uibutton(app.UIFigure, 'state');
            app.XButton.ValueChangedFcn = createCallbackFcn(app, @XButtonValueChanged, true);
            app.XButton.Text = 'X';
            app.YButton = uibutton(app.UIFigure, 'state');
            app.YButton.ValueChangedFcn = createCallbackFcn(app, @YButtonValueChanged, true);
            app.YButton.Text = 'Y';
            app.SelectingneuronjumpstoDropDownLabel = uilabel(app.UIFigure);
            app.SelectingneuronjumpstoDropDownLabel.Text = 'Selecting neuron jumps to';
            app.JumpList = uidropdown(app.UIFigure);
            app.JumpList.Items = {'Assigned Neuron', 'Nearest Slice', 'None'};
            app.JumpList.Value = 'Nearest Slice';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = check_cell_assignment

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end