classdef create_calcium_reference < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        FrameSliderLabel        matlab.ui.control.Label
        FrameSlider             matlab.ui.control.Slider
        SaveButton              matlab.ui.control.Button
        UIAxes1                 matlab.ui.control.UIAxes
        LoadButton              matlab.ui.control.Button
        UIAxes2                 matlab.ui.control.UIAxes
        FramesToAverageLabel    matlab.ui.control.Label
        FramesToAverageSpinner  matlab.ui.control.Spinner
    end

    
    properties (Access = private)
        config = get_config();
        data_folder
        reference_average
        reference_zstack
        multicolor_image = [];
    end
    
    methods (Access = private)
        function display_images(app)
            app.average_frames();
            
            imagesc(app.UIAxes1, app.reference_zstack);
            
            if ~isempty(app.multicolor_image)
                imagesc(app.UIAxes1, app.multicolor_image);
            end
        end
        
        function average_frames(app)
            % create_calcium_reference  Average together the first n volumes of a
            % calcium recording together. We will use this average volume to get
            % initial cell body locations
            
            % number of frames per stack in calcium recording are not constant. To
            % put it in a matrix choose which frames to keep
            num_frames_to_keep = app.config.num_frames_to_keep;
            
            % choose which volumes to average over. Usually good to average around
            % 2s and start 100 volumes in to avoid issues at the beginning
            volumes_to_grab = app.FrameSlider.Value : app.FrameSlider.Value + app.FramesToAverageSpinner.Value - 1;
            
            % volumes are recorded top -> bottom then bottom -> top
            % we need to flip these to align, so search for the shift that alligns
            % the flipped stacks
            shift_search_range = 5;
            
            % average together the red (true) or green channel (false)
            use_red_reference = true;
            
            %% flip the volumes that are measured backwards
            % Volumes that are measured backward will be flipped

            % get volumes
            if use_red_reference
                [initial_volumes, ~, z_loc] = app.load_calcium_from_dat(app.data_folder, volumes_to_grab, num_frames_to_keep);
            else
                [~, initial_volumes, z_loc] = app.load_calcium_from_dat(app.data_folder, volumes_to_grab, num_frames_to_keep);
            end

            % find the whether the first from goes top to bottom or the
            % reverse
            norm_mod = double(z_loc(1, 1) > z_loc(end, 1));

            % calculate the average of the non flipped volumes
            norm_vol_ind = 2 - norm_mod;
            norm_vol = mean(initial_volumes(:, :, :, norm_vol_ind:2:end), 4);
            % calculate the std over z for the volume that isn't flipped
            norm_std = permute(std(norm_vol, [], [1, 2]), [3, 1, 2]);
            norm_std = norm_std - norm_std(1);

            % calculate the average of the flipped volumes
            flip_vol_ind = norm_mod + 1;
            flip_vol = mean(initial_volumes(:, :, :, flip_vol_ind:2:end), 4);
            % calculate the std over z for the volume that is flipped
            flip_std = permute(std(flip_vol, [], [1, 2]), [3, 1, 2]);
            flip_std = flip_std - flip_std(1);

            % find the correlation between the first two frames
            [~, shift_val] = max(xcorr(norm_std, flip(flip_std, 1), shift_search_range));
            shift_val = shift_val - shift_search_range - 1;

            % truncate the frames so they overlap correctly after flipping
            if shift_val >= 0
                frames_to_keep_final = (1 + shift_val) : size(initial_volumes, 3);
            else
                frames_to_keep_final = 1 : size(initial_volumes, 3) + shift_val;
            end

            reference_volumes = initial_volumes(:, :, frames_to_keep_final, :);

            % flip the stacks that are aquired backward
            for vv = flip_vol_ind:2:size(reference_volumes, 4)
                reference_volumes(:, :, :, vv) = flip(reference_volumes(:, :, :, vv), 3);
            end

            % average volumes together
            app.reference_average = mean(reference_volumes, 4);
            app.reference_zstack = max(app.reference_avereage, [], 3);
        end
        
%         function [rows,cols] = get_dat_dimensions(~, string)
%             %getdatdimension takes a string produced by the whole brain imaging system,
%             %which has the image dimensions in the text, and parses the text to extract
%             %the rows and columns. string is in the format sCMOS_Frames_U16_1024x512.dat
%             %  [rows,cols]=getdatdimensions(string)
%         
%             xloc= find(string=='x',1,'last');
%             dotloc=find(string=='.',1,'last');
%             numstart= find(string=='_',1,'last');
%             rows=str2double(string(numstart+1:xloc-1));
%             cols=str2double(string(xloc+1:dotloc-1));
%         end
        
        function [red, green, z_loc] = load_calcium_from_dat(app, data_folder, volumes_to_grab, num_slices)
            % load_calcium_from_dat  will get all the volumes_to_grab volumes from a
            % calcium recording. All of this code was ripped from the 3dbrain
            % pipeline https://github.com/leiferlab/3dbrain
            
            if nargin < 3
                num_slices = [];
            end
            
            num_stacks = length(volumes_to_grab);
            
            % get the information from the high resolution microscope recording
            hiResData_path = fullfile(data_folder, 'hiResData.mat');
            hiResData = load(hiResData_path);
            hiResData = hiResData.dataAll;
            
            % z location of the scope
            zWave=hiResData.Z;
            zWave=gradient(zWave);
            zWave=smooth(zWave,100);
            
            % standard deviation of the image over z
            image_std=hiResData.imSTD;
            image_std=image_std-mean(image_std);
            image_std(image_std>150)=0;
            [ZSTDcorrplot,lags]=crosscorr(abs(zWave),image_std,30);
            ZSTDcorrplot=smooth(ZSTDcorrplot,3);
            zOffset=lags(ZSTDcorrplot==max(ZSTDcorrplot));
        
            %get image path
            datFileDir=dir([data_folder filesep 'sCMOS_Frames_U16_*']);
            datFile=[data_folder filesep datFileDir.name];
            
            %get image size
            [rows,cols] = app.get_dat_dimensions(datFile);
            nPix=rows*cols;
            
            alignments=load([data_folder filesep 'alignments']);
            alignments=alignments.alignments;
            
            % location on the camera of the red (rect1) and green (rect2) image
            rect1 = alignments.S2AHiRes.rect1;
            rect2 = alignments.S2AHiRes.rect2;
            
            Fid=fopen(datFile);
            
            % if frames_to_keep is not specified, read each volume into a cell array
            % if it is specified then keep only those frames from each volume and
            % save them to a matrix
            if isempty(num_slices)
                red = cell(num_stacks, 1);
                green = cell(num_stacks, 1);
                z_loc = cell(num_stacks, 1);
            else
                red_rows = rect1(4) - rect1(2);
                red_cols = rect1(3) - rect1(1);
                green_rows = rows - rect1(4);
                green_cols = rect1(3) - rect1(1);
                red = zeros(red_rows, red_cols, num_slices, num_stacks);
                green = zeros(green_rows, green_cols, num_slices, num_stacks);
                z_loc = zeros(num_slices, num_stacks);
            end
            
            % grab the frames associated with each volume
            for ii = 1:num_stacks
                %select frames to analyze
                hiResIdx=find(hiResData.stackIdx==volumes_to_grab(ii))+ zOffset;
        
                %do something with status errors!
                status=fseek(Fid,2*(hiResIdx(1))*nPix,-1);
        
                pixelValues=fread(Fid,nPix*(length(hiResIdx)),'uint16',0,'l');
                hiResImage=reshape(pixelValues,rows,cols,length(hiResIdx));


                % put each volume into either a cell array or a matrix
                if isempty(num_slices)
                    red{ii}=hiResImage((rect1(2)+1):rect1(4),(1+rect1(1)):rect1(3),:);
                    green{ii}=hiResImage((rect1(4)+1):end,(1+rect1(1)):rect1(3),:);
                    z_loc{ii} = hiResData.Z(hiResIdx);
                else
                    z_loc_init = hiResData.Z(hiResIdx);
                    
                    if z_loc_init(1) > z_loc_init(end)
                        frames_to_keep = 1 : num_slices;
                    else
                        frames_to_keep = (size(hiResImage, 3) - num_slices + 1) : size(hiResImage, 3);
                    end
                    
                    red(:, :, :, ii)=hiResImage((rect1(2)+1):rect1(4),(1+rect1(1)):rect1(3),frames_to_keep);
                    green(:, :, :, ii)=hiResImage((rect1(4)+1):end,(1+rect1(1)):rect1(3),frames_to_keep);
                    z_loc(:, ii) = z_loc_init(frames_to_keep);
                end
            end
            
            fclose(Fid);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            axis(app.UIAxes1, 'equal');
            axis(app.UIAxes2, 'equal');
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            app.data_folder = uigetdir(app.config.panneuronal_path, 'Select the brainscanner folder');
    
            if all(app.data_folder == 0)
                return;
            end
            
            app.display_images();
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            
%             %% Convert to neuropal format
%             save_path = fullfile(app.data_folder, 'calcium_data_average_stack.mat');
% 
%             volume_backsub = uint16(reference_average - app.config.cmos_background_value);
%             
%             % neuropal software requires a 4d matrix with the 4th dimension
%             % representing the different color channels
%             % just duplicate the tagRFP data
%             volume_backsub = repmat(volume_backsub, [1, 1, 1, 5]);
%             
%             neuropal_input = get_default_neuropal_input(volume_backsub, save_path);
%             neuropal_input.shift_val = shift_val;
%             
%             
%             %% save the volume
%             save(save_path, '-struct', 'neuropal_input');
% 
%             % remove the old neuropal file so that neuropal doesn't load it
%             old_file_path = fullfile(app.data_folder, 'calcium_data_average_stack_ID.mat');
% 
%             if exist(old_file_path, 'file')
%                 delete(old_file_path);
%             end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.FrameSliderLabel = uilabel(app.UIFigure);
            app.FrameSliderLabel.Text = 'Frame';
            app.FrameSlider = uislider(app.UIFigure);
            app.FrameSlider.Limits = [1 1000];
            app.FrameSlider.Value = 1;
            app.SaveButton = uibutton(app.UIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.UIAxes1 = uiaxes(app.UIFigure);
            title(app.UIAxes1, '')
            xlabel(app.UIAxes1, '')
            ylabel(app.UIAxes1, '')
            app.UIAxes1.XTick = [];
            app.UIAxes1.YTick = [];
            app.LoadButton = uibutton(app.UIFigure, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, '')
            xlabel(app.UIAxes2, '')
            ylabel(app.UIAxes2, '')
            app.UIAxes2.XTick = [];
            app.UIAxes2.YTick = [];
            app.FramesToAverageLabel = uilabel(app.UIFigure);
            app.FramesToAverageLabel.Text = 'Frames To Average';
            app.FramesToAverageSpinner = uispinner(app.UIFigure);
            app.FramesToAverageSpinner.Limits = [1 Inf];
            app.FramesToAverageSpinner.Value = 1;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = create_calcium_reference

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end