classdef export_multicolor_zstack < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        UIAxes              matlab.ui.control.UIAxes
        LoadButton          matlab.ui.control.Button
        ZDepth1SliderLabel  matlab.ui.control.Label
        ZDepth1Slider       matlab.ui.control.Slider
        ZDepth2SliderLabel  matlab.ui.control.Label
        ZDepth2Slider       matlab.ui.control.Slider
        ExportButton        matlab.ui.control.Button
        TitleLabel          matlab.ui.control.Label
    end

    
    properties (Access = private)
        multicolor_image
        multicolor_z_stack
        path
        z_loc_1
        z_loc_2
        config
        rgb_indicies = [5, 3, 1];
    end
    
    methods (Access = private)
        function display_stack(app)
            app.create_z_stack();
            
            imagesc(app.UIAxes, app.multicolor_z_stack);
        end
        
        function create_z_stack(app)
            if app.z_loc_1 <= app.z_loc_2
                z_start = app.z_loc_1;
                z_end = app.z_loc_2;
            else
                z_start = app.z_loc_2;
                z_end = app.z_loc_1;
            end
            
            multicolor_sub_image = app.multicolor_image(:, :, z_start:z_end, :);
            
            app.multicolor_z_stack = squeeze(max(multicolor_sub_image, [], 3));
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            axis(app.UIAxes, 'equal');
        end

        % Value changed function: ZDepth1Slider
        function ZDepth1SliderValueChanged(app, event)
            value = app.ZDepth1Slider.Value;
            app.ZDepth1Slider.Value = round(value);
        end

        % Value changed function: ZDepth2Slider
        function ZDepth2SliderValueChanged(app, event)
            value = app.ZDepth2Slider.Value;
            app.ZDepth2Slider.Value = round(value);
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            app.config = get_config();
            
            [file, path_in] = uigetfile([app.config.data_location '/*neuropal_data.mat'], 'Select neuropal_data file in multicolor folder');
            
            if all(file == 0)
                return;
            end
            
            app.path = fullfile(path_in, file);
            data_in = load(app.path, '-mat');
            
            data_pre_gamma = double(data_in.data(:, :, :, app.rgb_indicies));
            data_pre_gamma = data_pre_gamma / max(data_pre_gamma(:));
            gamma_val = data_in.prefs.gamma;
            
            gamma_image = zeros(size(data_pre_gamma));
            for ss = 1:size(gamma_image, 3)
                for ff = 1:size(gamma_image, 4)
                    gamma_image(:, :, ss, ff) = imadjust(data_pre_gamma(:, :, ss, ff), [], [], gamma_val(ff));
                end
            end
            
            app.multicolor_image = gamma_image;
            
            [date_folder_path, enclosing_folder] = fileparts(path_in(1:end-1));
            [~, date_folder] = fileparts(date_folder_path);
            file_name = fullfile(date_folder, enclosing_folder);
            
            % set the title to reflect the file you loaded
            app.TitleLabel.Text = file_name;
            
            % set the z depth limits
            num_slices = size(app.multicolor_image, 3);
            app.ZDepth1Slider.Limits = [1, num_slices];
            app.ZDepth2Slider.Limits = [1, num_slices];

            app.ZDepth1Slider.Value = 1;
            app.ZDepth2Slider.Value = floor(num_slices/2);
            
            app.z_loc_1 = app.ZDepth1Slider.Value;
            app.z_loc_2 = app.ZDepth2Slider.Value;
            
            app.display_stack;
        end

        % Value changing function: ZDepth1Slider
        function ZDepth1SliderValueChanging(app, event)
            current_loc = event.Value;
            app.z_loc_1 = round(current_loc);
            app.display_stack();
        end

        % Value changing function: ZDepth2Slider
        function ZDepth2SliderValueChanging(app, event)
            current_loc = event.Value;
            app.z_loc_2 = round(current_loc);
            app.display_stack();
        end

        % Button pushed function: ExportButton
        function ExportButtonPushed(app, event)
            app.ExportButton.Text = 'PLEASE WAIT';
            pause(0.1);
            
            file_name = ['z_stack_' num2str(app.z_loc_1) '-' num2str(app.z_loc_2) '.png'];
            save_path = fullfile(fileparts(app.path), file_name);
            imwrite(app.multicolor_z_stack, save_path);
            
            app.ExportButton.Text = 'Export';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.LoadButton = uibutton(app.UIFigure, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.ZDepth1SliderLabel = uilabel(app.UIFigure);
            app.ZDepth1SliderLabel.Text = 'Z Depth 1';
            app.ZDepth1Slider = uislider(app.UIFigure);
            app.ZDepth1Slider.Limits = [1 31];
            app.ZDepth1Slider.ValueChangedFcn = createCallbackFcn(app, @ZDepth1SliderValueChanged, true);
            app.ZDepth1Slider.ValueChangingFcn = createCallbackFcn(app, @ZDepth1SliderValueChanging, true);
            app.ZDepth1Slider.Value = 1;
            app.ZDepth2SliderLabel = uilabel(app.UIFigure);
            app.ZDepth2SliderLabel.Text = 'Z Depth 2';
            app.ZDepth2Slider = uislider(app.UIFigure);
            app.ZDepth2Slider.Limits = [1 31];
            app.ZDepth2Slider.ValueChangedFcn = createCallbackFcn(app, @ZDepth2SliderValueChanged, true);
            app.ZDepth2Slider.ValueChangingFcn = createCallbackFcn(app, @ZDepth2SliderValueChanging, true);
            app.ZDepth2Slider.Value = 15;
            app.ExportButton = uibutton(app.UIFigure, 'push');
            app.ExportButton.ButtonPushedFcn = createCallbackFcn(app, @ExportButtonPushed, true);
            app.ExportButton.Text = 'Export';
            app.TitleLabel = uilabel(app.UIFigure);
            app.TitleLabel.Text = 'TitleLabel';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = export_multicolor_zstack

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end